classdef swrAnalysis < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    swrAnalysisUIFigure          matlab.ui.Figure
    transposeCheckbox            matlab.ui.control.CheckBox
    expAveCheckbox               matlab.ui.control.CheckBox
    dataFile3Loaded              matlab.ui.control.Label
    openFile3Button              matlab.ui.control.Button
    saveFileButton               matlab.ui.control.Button
    openNextFileButton           matlab.ui.control.Button
    openPrevFileButton           matlab.ui.control.Button
    openFileButton               matlab.ui.control.Button
    closeFileButton              matlab.ui.control.Button
    openFile2Button              matlab.ui.control.Button
    dataFile2Loaded              matlab.ui.control.Label
    dataFile1Loaded              matlab.ui.control.Label
    TabGroup                     matlab.ui.container.TabGroup
    LFPTab                       matlab.ui.container.Tab
    filtTypeButtonGroup          matlab.ui.container.ButtonGroup
    CustomgaussianButton         matlab.ui.control.RadioButton
    MATLABbandpassButton         matlab.ui.control.RadioButton
    plotSpecButton               matlab.ui.control.Button
    dsPlotLFPField               matlab.ui.control.NumericEditField
    DownsamplePlotLabel          matlab.ui.control.Label
    SpectralAnalysisPanel        matlab.ui.container.Panel
    nShuffleField                matlab.ui.control.NumericEditField
    nShuffleLabel                matlab.ui.control.Label
    toLabel_10                   matlab.ui.control.Label
    xFreqBinField                matlab.ui.control.NumericEditField
    BinLabel                     matlab.ui.control.Label
    HzLabel_18                   matlab.ui.control.Label
    sLabel_3                     matlab.ui.control.Label
    sLabel_2                     matlab.ui.control.Label
    winOverlapField              matlab.ui.control.NumericEditField
    OverlapLabel                 matlab.ui.control.Label
    winLengthField               matlab.ui.control.NumericEditField
    WindowLabel                  matlab.ui.control.Label
    morlWidthField               matlab.ui.control.NumericEditField
    WidthLabel                   matlab.ui.control.Label
    xFreqLamp                    matlab.ui.control.Lamp
    fftLamp                      matlab.ui.control.Lamp
    xFreqCheckBox                matlab.ui.control.CheckBox
    phaseCheckBox                matlab.ui.control.CheckBox
    fftCheckBox                  matlab.ui.control.CheckBox
    xFreqLowDropDown             matlab.ui.control.DropDown
    xFreqLowLabel                matlab.ui.control.Label
    spectLim2Field               matlab.ui.control.NumericEditField
    spectLim1Field               matlab.ui.control.NumericEditField
    HzLabel_8                    matlab.ui.control.Label
    spectLamp                    matlab.ui.control.Lamp
    spectCheckBox                matlab.ui.control.CheckBox
    importStimCheckBox           matlab.ui.control.CheckBox
    HzLabel_16                   matlab.ui.control.Label
    notchFiltFreqField           matlab.ui.control.NumericEditField
    notchFiltCheckBox            matlab.ui.control.CheckBox
    lfpChannelLamp               matlab.ui.control.Lamp
    cellChannelLamp              matlab.ui.control.Lamp
    cellChannelField             matlab.ui.control.NumericEditField
    CellChannelLabel             matlab.ui.control.Label
    cellCheckBox                 matlab.ui.control.CheckBox
    swrDetectCheckBox            matlab.ui.control.CheckBox
    OtherFiltersPanel            matlab.ui.container.Panel
    toLabelFRipple               matlab.ui.control.Label
    toLabelHGamma                matlab.ui.control.Label
    toLabelGamma                 matlab.ui.control.Label
    toLabelBeta                  matlab.ui.control.Label
    toLabelAlpha                 matlab.ui.control.Label
    toLabeltheta                 matlab.ui.control.Label
    alphaLim2Field               matlab.ui.control.NumericEditField
    alphaLim1Field               matlab.ui.control.NumericEditField
    HzLabel_17                   matlab.ui.control.Label
    alphaFiltLamp                matlab.ui.control.Lamp
    alphaFiltCheckBox            matlab.ui.control.CheckBox
    fRLim2Field                  matlab.ui.control.NumericEditField
    fRLim1Field                  matlab.ui.control.NumericEditField
    HzLabel_14                   matlab.ui.control.Label
    fRFiltLamp                   matlab.ui.control.Lamp
    fRFiltCheckBox               matlab.ui.control.CheckBox
    HzLabel_5                    matlab.ui.control.Label
    HzLabel_4                    matlab.ui.control.Label
    HzLabel_3                    matlab.ui.control.Label
    HzLabel_2                    matlab.ui.control.Label
    betaLim2Field                matlab.ui.control.NumericEditField
    betaLim1Field                matlab.ui.control.NumericEditField
    betaFiltLamp                 matlab.ui.control.Lamp
    betaFiltCheckBox             matlab.ui.control.CheckBox
    hgammaLim2Field              matlab.ui.control.NumericEditField
    hgammaLim1Field              matlab.ui.control.NumericEditField
    hgammaFiltLamp               matlab.ui.control.Lamp
    hgammaFiltCheckBox           matlab.ui.control.CheckBox
    gammaLim2Field               matlab.ui.control.NumericEditField
    gammaLim1Field               matlab.ui.control.NumericEditField
    thetaLim2Field               matlab.ui.control.NumericEditField
    thetaLim1Field               matlab.ui.control.NumericEditField
    gammaFiltLamp                matlab.ui.control.Lamp
    thetaFiltLamp                matlab.ui.control.Lamp
    gammaFiltCheckBox            matlab.ui.control.CheckBox
    thetaFiltCheckBox            matlab.ui.control.CheckBox
    SWRDetectionPanel            matlab.ui.container.Panel
    peakDetectSWRLamp            matlab.ui.control.Lamp
    baseDetectSWRLamp            matlab.ui.control.Lamp
    peakDetectSWRCheckbox        matlab.ui.control.CheckBox
    baseDetectLFPCheckbox        matlab.ui.control.CheckBox
    swrTypeButtonGroup           matlab.ui.container.ButtonGroup
    msLabel_6                    matlab.ui.control.Label
    rmsMinEvDurField             matlab.ui.control.NumericEditField
    MinDurLabel                  matlab.ui.control.Label
    sdBaseFactorRField           matlab.ui.control.NumericEditField
    BaseFactorLabel_2            matlab.ui.control.Label
    sdMultRField                 matlab.ui.control.NumericEditField
    SDMultThreshLabel_2          matlab.ui.control.Label
    rmsMinEvDiffField            matlab.ui.control.NumericEditField
    MinIEILabel                  matlab.ui.control.Label
    msLabel_5                    matlab.ui.control.Label
    sdBaseFactorSWField          matlab.ui.control.NumericEditField
    BaseFactorLabel              matlab.ui.control.Label
    sdMultSWField                matlab.ui.control.NumericEditField
    SDMultThreshLabel            matlab.ui.control.Label
    RippleOnlyButton             matlab.ui.control.RadioButton
    SWOnlyButton                 matlab.ui.control.RadioButton
    SWAndRippleButton            matlab.ui.control.RadioButton
    rmsPeriodRField              matlab.ui.control.NumericEditField
    RMSPeriodLabel_2             matlab.ui.control.Label
    msLabel_4                    matlab.ui.control.Label
    baseDetectLFPButtonGroup     matlab.ui.container.ButtonGroup
    skewedBLLFPCheckBox          matlab.ui.control.CheckBox
    plotFitHistoLFPCheckBox      matlab.ui.control.CheckBox
    excludeQuantLFPField         matlab.ui.control.NumericEditField
    ExclusionQuantileLabel_2     matlab.ui.control.Label
    kurtosisMaxLFPField          matlab.ui.control.NumericEditField
    KurtosisMaxLabel_2           matlab.ui.control.Label
    kurtosisMinLFPField          matlab.ui.control.NumericEditField
    KurtosisMinLabel_2           matlab.ui.control.Label
    pkSimLimLFPField             matlab.ui.control.NumericEditField
    PeakSimilarityLimitLabel_2   matlab.ui.control.Label
    pkDiffMinLFPField            matlab.ui.control.NumericEditField
    FracDiffbetweenPeaksLabel    matlab.ui.control.Label
    baseQuantLFPField            matlab.ui.control.NumericEditField
    baseDetectLFPButton2         matlab.ui.control.RadioButton
    baseDetectLFPButton1         matlab.ui.control.RadioButton
    SWRReviewPanel               matlab.ui.container.Panel
    nSWREvLabel                  matlab.ui.control.Label
    textLabel                    matlab.ui.control.Label
    selSWREvLabel                matlab.ui.control.Label
    nextSWREvButton              matlab.ui.control.Button
    prevSWREvButton              matlab.ui.control.Button
    rejectSWREvButton            matlab.ui.control.Button
    swrWindowField               matlab.ui.control.NumericEditField
    SWRWindowLabel               matlab.ui.control.Label
    rmsPeriodSWField             matlab.ui.control.NumericEditField
    RMSPeriodLabel               matlab.ui.control.Label
    rLim2Field                   matlab.ui.control.NumericEditField
    rLim2Label                   matlab.ui.control.Label
    rLim1Field                   matlab.ui.control.NumericEditField
    rLim1Label                   matlab.ui.control.Label
    swLim2Field                  matlab.ui.control.NumericEditField
    swLim2Label                  matlab.ui.control.Label
    swLim1Field                  matlab.ui.control.NumericEditField
    swLim1Label                  matlab.ui.control.Label
    expSWRDataCheckbox           matlab.ui.control.CheckBox
    HzLabel_7                    matlab.ui.control.Label
    HzLabel_6                    matlab.ui.control.Label
    msLabel_3                    matlab.ui.control.Label
    msLabel_2                    matlab.ui.control.Label
    expSWREvCheckbox             matlab.ui.control.CheckBox
    rFiltLamp                    matlab.ui.control.Lamp
    swFiltLamp                   matlab.ui.control.Lamp
    rFiltCheckBox                matlab.ui.control.CheckBox
    swFiltCheckBox               matlab.ui.control.CheckBox
    HzLabel                      matlab.ui.control.Label
    reanalyzeLFPButton           matlab.ui.control.Button
    plotLFPButton                matlab.ui.control.Button
    analyzeLFPButton             matlab.ui.control.Button
    lfpFiltLamp                  matlab.ui.control.Lamp
    dsFactorField                matlab.ui.control.NumericEditField
    DownsampleDataLabel          matlab.ui.control.Label
    lpfChannelField              matlab.ui.control.NumericEditField
    LFPChannelLabel              matlab.ui.control.Label
    FsField                      matlab.ui.control.NumericEditField
    SamplingFrequencyLabel       matlab.ui.control.Label
    lfpLim2Field                 matlab.ui.control.NumericEditField
    lfpLim2Label                 matlab.ui.control.Label
    lfpLim1Field                 matlab.ui.control.NumericEditField
    lfpLim1Label                 matlab.ui.control.Label
    lfpFiltCheckBox              matlab.ui.control.CheckBox
    fileTypeButtonGroup          matlab.ui.container.ButtonGroup
    matButton                    matlab.ui.control.RadioButton
    ASCIIButton                  matlab.ui.control.RadioButton
    abfButton                    matlab.ui.control.RadioButton
    fileNumLFPButtonGroup        matlab.ui.container.ButtonGroup
    MultipleLFPButton            matlab.ui.control.RadioButton
    SingleLFPButton              matlab.ui.control.RadioButton
    spkTab                       matlab.ui.container.Tab
    dsPlotSpkField               matlab.ui.control.NumericEditField
    DownsamplePlotLabel_2        matlab.ui.control.Label
    calcPhaseStatsCheckbox       matlab.ui.control.CheckBox
    spkLFPButtonGroup            matlab.ui.container.ButtonGroup
    spkAmpLFPLamp                matlab.ui.control.Lamp
    spkPhaseLFPLamp              matlab.ui.control.Lamp
    spkPhaseLFPCheckbox          matlab.ui.control.CheckBox
    spkAmpLFPCheckbox            matlab.ui.control.CheckBox
    sdMultPhaseField             matlab.ui.control.NumericEditField
    MinMaxOscillationSDThresholdforphaseLabel  matlab.ui.control.Label
    spkAnalysisTypeButtonGroup   matlab.ui.container.ButtonGroup
    lfpSpkLamp                   matlab.ui.control.Lamp
    swrSpkLamp                   matlab.ui.control.Lamp
    swrBstLamp                   matlab.ui.control.Lamp
    swrBstCheckBox               matlab.ui.control.CheckBox
    lfpSpkButton                 matlab.ui.control.RadioButton
    swrSpkButton                 matlab.ui.control.RadioButton
    reanalyzeSpkButton           matlab.ui.control.Button
    expBstEvCheckBox             matlab.ui.control.CheckBox
    expSpkEvCheckBox             matlab.ui.control.CheckBox
    importSpkLamp                matlab.ui.control.Lamp
    spkSWRButtonGroup            matlab.ui.container.ButtonGroup
    spkPhaseSWRLamp              matlab.ui.control.Lamp
    spkPhaseSWRCheckbox          matlab.ui.control.CheckBox
    expSWRSpkCheckbox            matlab.ui.control.CheckBox
    useSWRWindowSpkLamp          matlab.ui.control.Lamp
    useSWRDurationSpkLamp        matlab.ui.control.Lamp
    useSWRWindowSpkButton        matlab.ui.control.RadioButton
    useSWRDurationSpkButton      matlab.ui.control.RadioButton
    importSpkCheckBox            matlab.ui.control.CheckBox
    plotSpkButton                matlab.ui.control.Button
    analyzeSpkButton             matlab.ui.control.Button
    fileNumSpkButtonGroup        matlab.ui.container.ButtonGroup
    MultipleSpkButton            matlab.ui.control.RadioButton
    SingleSpkButton              matlab.ui.control.RadioButton
    PSCTab                       matlab.ui.container.Tab
    dsPlotPSCField               matlab.ui.control.NumericEditField
    DownsamplePlotLabel_3        matlab.ui.control.Label
    swrPSQCheckBox               matlab.ui.control.CheckBox
    PSCPolarityLabel             matlab.ui.control.Label
    pscEventPolarityButtonGroup  matlab.ui.container.ButtonGroup
    pscNegPolarityButton         matlab.ui.control.RadioButton
    pscPosPolarityButton         matlab.ui.control.RadioButton
    spectLim2PSCField            matlab.ui.control.NumericEditField
    gammaLim2Label_7             matlab.ui.control.Label
    spectLim1PSCField            matlab.ui.control.NumericEditField
    gammaLim1Label_7             matlab.ui.control.Label
    HzLabel_15                   matlab.ui.control.Label
    spectPSCLamp                 matlab.ui.control.Lamp
    spectPSCCheckBox             matlab.ui.control.CheckBox
    FiltersPSCPanel              matlab.ui.container.Panel
    HzLabel_12                   matlab.ui.control.Label
    HzLabel_10                   matlab.ui.control.Label
    rLim2PSCField                matlab.ui.control.NumericEditField
    gammaLim2Label_4             matlab.ui.control.Label
    rLim1PSCField                matlab.ui.control.NumericEditField
    gammaLim1Label_4             matlab.ui.control.Label
    gammaLim2PSCField            matlab.ui.control.NumericEditField
    thetaLim2Label_3             matlab.ui.control.Label
    gammaLim1PSCField            matlab.ui.control.NumericEditField
    thetaLim1Label_3             matlab.ui.control.Label
    rFiltPSCLamp                 matlab.ui.control.Lamp
    gammaFiltPSCLamp             matlab.ui.control.Lamp
    rFiltPSCCheckBox             matlab.ui.control.CheckBox
    gammaFiltPSCCheckBox         matlab.ui.control.CheckBox
    reanalyzePSCButton           matlab.ui.control.Button
    expPSCEvCheckBox             matlab.ui.control.CheckBox
    swrPSCLamp                   matlab.ui.control.Lamp
    importPSCLamp                matlab.ui.control.Lamp
    swrPSCButtonGroup            matlab.ui.container.ButtonGroup
    expSWRPSCCheckbox            matlab.ui.control.CheckBox
    calcEvMatrixPSCLamp          matlab.ui.control.Lamp
    parsePSCLamp                 matlab.ui.control.Lamp
    useSWRWindowPSCLamp          matlab.ui.control.Lamp
    useSWRDurationPSCLamp        matlab.ui.control.Lamp
    parsePSCCheckBox             matlab.ui.control.CheckBox
    calcEvMatrixPSCCheckBox      matlab.ui.control.CheckBox
    useSWRWindowPSCButton        matlab.ui.control.RadioButton
    useSWRDurationPSCButton      matlab.ui.control.RadioButton
    swrPSCCheckBox               matlab.ui.control.CheckBox
    importPSCCheckBox            matlab.ui.control.CheckBox
    plotPSCButton                matlab.ui.control.Button
    analyzePSCButton             matlab.ui.control.Button
    fileNumPSCButtonGroup        matlab.ui.container.ButtonGroup
    MultiplePSCButton            matlab.ui.control.RadioButton
    SinglePSCButton              matlab.ui.control.RadioButton
    CaTab                        matlab.ui.container.Tab
    HzLabel_19                   matlab.ui.control.Label
    CaFrameRateField             matlab.ui.control.NumericEditField
    CaFrameRateCheckbox          matlab.ui.control.CheckBox
    CaFreqOptionCheckbox         matlab.ui.control.CheckBox
    plotCaCorrButton             matlab.ui.control.Button
    cellTypeOptionCheckbox       matlab.ui.control.CheckBox
    dsPlotCaField                matlab.ui.control.NumericEditField
    DownsamplePlotLabel_4        matlab.ui.control.Label
    cellTypePanel                matlab.ui.container.Panel
    cellType6EditField           matlab.ui.control.EditField
    Type6Label                   matlab.ui.control.Label
    nCellTypesField              matlab.ui.control.NumericEditField
    NumberofGroupsLabel          matlab.ui.control.Label
    cellType2EditField           matlab.ui.control.EditField
    Type2Label                   matlab.ui.control.Label
    cellType5EditField           matlab.ui.control.EditField
    Type5Label                   matlab.ui.control.Label
    cellType4EditField           matlab.ui.control.EditField
    Type4Label                   matlab.ui.control.Label
    cellType3EditField           matlab.ui.control.EditField
    Type3Label                   matlab.ui.control.Label
    cellType1EditField           matlab.ui.control.EditField
    Type1EditFieldLabel          matlab.ui.control.Label
    baseCorrectCaCheckbox        matlab.ui.control.CheckBox
    alignOptionGroup             matlab.ui.container.ButtonGroup
    alignOptionEndButton         matlab.ui.control.RadioButton
    alignOptionStartButton       matlab.ui.control.RadioButton
    sdBaseFactorCaField          matlab.ui.control.NumericEditField
    BaseFactorLabel_3            matlab.ui.control.Label
    stimCaCheckBox               matlab.ui.control.CheckBox
    swrCaLamp                    matlab.ui.control.Lamp
    swrCaCheckbox                matlab.ui.control.CheckBox
    stimCaPanel                  matlab.ui.container.Panel
    expStimCaCheckbox            matlab.ui.control.CheckBox
    stimCaLim2Field              matlab.ui.control.NumericEditField
    toLabel_2                    matlab.ui.control.Label
    stimCaLim1Field              matlab.ui.control.NumericEditField
    fromLabel                    matlab.ui.control.Label
    msafterstimulationpeakLabel  matlab.ui.control.Label
    baseDetectCaLamp             matlab.ui.control.Lamp
    baseCorrectCaLamp            matlab.ui.control.Lamp
    baseDetectCaCheckbox         matlab.ui.control.CheckBox
    sLabel                       matlab.ui.control.Label
    skipDetectLimField           matlab.ui.control.NumericEditField
    SkipdetectionforfirstLabel   matlab.ui.control.Label
    baseCorrectCaButtonGroup     matlab.ui.container.ButtonGroup
    smoothFactorField            matlab.ui.control.NumericEditField
    SmoothFactorLabel            matlab.ui.control.Label
    CaFiltAlphaField             matlab.ui.control.NumericEditField
    FilterAlphaLabel             matlab.ui.control.Label
    CaFiltOrderField             matlab.ui.control.NumericEditField
    FilterOrderLabel             matlab.ui.control.Label
    toLabel                      matlab.ui.control.Label
    HzLabel_9                    matlab.ui.control.Label
    CaLim2Field                  matlab.ui.control.NumericEditField
    CaLim1Field                  matlab.ui.control.NumericEditField
    baseCorrectCaButton2         matlab.ui.control.RadioButton
    baseCorrectCaButton1         matlab.ui.control.RadioButton
    baseDetectCaButtonGroup      matlab.ui.container.ButtonGroup
    nSDLimCaField                matlab.ui.control.NumericEditField
    KurtosisMinLabel_5           matlab.ui.control.Label
    minBinsBLCaField             matlab.ui.control.NumericEditField
    KurtosisMinLabel_4           matlab.ui.control.Label
    weightFactorCaField          matlab.ui.control.NumericEditField
    KurtosisMinLabel_3           matlab.ui.control.Label
    skewedBLCaCheckBox           matlab.ui.control.CheckBox
    plotFitHistoCaCheckBox       matlab.ui.control.CheckBox
    excludeQuantCaField          matlab.ui.control.NumericEditField
    ExclusionQuantileLabel       matlab.ui.control.Label
    kurtosisMaxCaField           matlab.ui.control.NumericEditField
    KurtosisMaxLabel             matlab.ui.control.Label
    kurtosisMinCaField           matlab.ui.control.NumericEditField
    KurtosisMinLabel             matlab.ui.control.Label
    pkSimLimCaField              matlab.ui.control.NumericEditField
    PeakSimilarityLimitLabel     matlab.ui.control.Label
    pkDiffMinCaField             matlab.ui.control.NumericEditField
    FracDiffbetweenPeaksLabel_2  matlab.ui.control.Label
    baseQuantCaField             matlab.ui.control.NumericEditField
    baseDetectCaButton2          matlab.ui.control.RadioButton
    baseDetectCaButton1          matlab.ui.control.RadioButton
    consistentThreshCheckBox     matlab.ui.control.CheckBox
    swrCaButtonGroup             matlab.ui.container.ButtonGroup
    expSWRCaCheckbox             matlab.ui.control.CheckBox
    useSWRWindowCaButton         matlab.ui.control.RadioButton
    useSWRDurationCaButton       matlab.ui.control.RadioButton
    plotCaButton                 matlab.ui.control.Button
    peakDetectCaLamp             matlab.ui.control.Lamp
    interpCaLamp                 matlab.ui.control.Lamp
    peakDetectCaCheckbox         matlab.ui.control.CheckBox
    interpCheckbox               matlab.ui.control.CheckBox
    expCaCheckbox                matlab.ui.control.CheckBox
    SDLabel_2                    matlab.ui.control.Label
    reanalyzeCaButton            matlab.ui.control.Button
    analyzeCaButton              matlab.ui.control.Button
    msLabel                      matlab.ui.control.Label
    samplingIntCaField           matlab.ui.control.NumericEditField
    SamplingIntervalLabel        matlab.ui.control.Label
    sdMultCaField                matlab.ui.control.NumericEditField
    SDMultThreshLabel_3          matlab.ui.control.Label
    fileNumCaButtonGroup         matlab.ui.container.ButtonGroup
    MultipleButtonCa             matlab.ui.control.RadioButton
    SingleButtonCa               matlab.ui.control.RadioButton
    ResultsTab                   matlab.ui.container.Tab
    exportEventsButton           matlab.ui.control.Button
    varValueTable                matlab.ui.control.Table
    varTypeTextArea              matlab.ui.control.TextArea
    TypeLabel                    matlab.ui.control.Label
    resultTree                   matlab.ui.container.Tree
  end

  
  properties (Access = public)
    data       % structure containing all data to be saved
    data2      % structure containing 2nd data set for comparisons
    data3      % structure containing 3rd data set for comparisons
    hand       % handle structure for figures
    param      % structure for all parameters entered in UI for analysis/plotting
    selNode    % selected node in results UI tree
    selVar     % selected variable in results UI tree
    selData    % currently selected data structure (data or data2)
    selEvent   % currently selected SWR event
    parentPath % path of folder containing opened datafile (only used for data, not second data2 file)
    dataFiles  % cell array containing all *.mat files in parentPath
    
  end
  
  methods (Access = private)
    
    function updateUITree(app, dataStruct)
      varNames1    = fieldnames(dataStruct);
      dataFileCat  = uitreenode(app.resultTree,'Text', dataStruct.saveName, 'NodeData',[]);
      for i1 = 1:length(varNames1)
        dataCat1 = uitreenode(dataFileCat,'Text',varNames1{i1},'NodeData', []);
        if isstruct(dataStruct.(varNames1{i1}))
          varNames2 = fieldnames(dataStruct.(varNames1{i1}));
          for i2 = 1:length(varNames2)
            dataCat2 = uitreenode(dataCat1,'Text',varNames2{i2},'NodeData', []);
            if isstruct(dataStruct.(varNames1{i1}).(varNames2{i2}))
              varNames3 = fieldnames(dataStruct.(varNames1{i1}).(varNames2{i2}));
              for i3 = 1:length(varNames3)
                uitreenode(dataCat2,'Text',varNames3{i3},'NodeData', []);
              end
            end
          end
        end
      end
    end
    
    function updateDataOptions(app)
      presentColor = [0.00 1.00 0.00];  
      missingColor = [0.94 0.87 0.82];
      
      % LFP Options
      if isfield(app.selData, 'LFP')
        app.lfpChannelLamp.Color = presentColor;  
        if isfield(app.selData.LFP, 'tSeries')
          app.lfpFiltLamp.Color = presentColor;
          app.plotLFPButton.Enable = 1;
        else
          app.lfpFiltLamp.Color = missingColor;
          app.plotLFPButton.Enable = 0;
        end
        
        % Spectrogram Options
        if isfield(app.selData.LFP, 'spect')
          app.spectLamp.Color = presentColor;
        end

        % FFT Options
        if isfield(app.selData.LFP, 'FFT')
          app.fftLamp.Color = presentColor;
          app.plotSpecButton.Enable = 1;
        else
          app.fftLamp.Color = missingColor;
          app.plotSpecButton.Enable = 0;
        end

        % Phase-Amplitude Options
        if isfield(app.selData.LFP, 'xFreq')
          app.xFreqLamp.Color = presentColor;
          app.plotSpecButton.Enable = 1;
        else
          app.xFreqLamp.Color = missingColor;
          app.plotSpecButton.Enable = 0;
        end
        
        updateXFreqDropdowns(app)
        
      else
        app.lfpChannelLamp.Color  = missingColor;
        app.lfpFiltLamp.Color     = missingColor;
        app.spectLamp.Color       = missingColor;
        app.fftLamp.Color         = missingColor;
        app.xFreqLamp.Color       = missingColor;
        app.plotLFPButton.Enable  = 0;
        app.plotSpecButton.Enable = 0;
      end
      
      % Cell Channel Options
      if isfield(app.selData, 'C')
        app.cellChannelLamp.Color = presentColor;
        if isfield(app.selData.C, 'spike')
          if isfield(app.selData, 'SWR')
            if isfield(app.selData.SWR, 'spike')
              app.plotSpkButton.Enable = 1;
            end
          elseif isfield(app.selData.C.spike, 'theta') || isfield(app.selData.C.spike, 'beta') || isfield(app.selData.C.spike, 'gamma') || isfield(app.selData.C.spike, 'hgamma')
            app.plotSpkButton.Enable = 1;
          end
        else
          app.plotSpkButton.Enable = 0;
        end
        
        if isfield(app.selData.C, 'PSC')
          if isfield(app.selData, 'SWR')
            if isfield(app.selData.SWR, 'PSC')
              app.plotPSCButton.Enable = 1;
            end
          end
        else
          app.plotPSCButton.Enable = 0;
        end
        
      else
        app.cellChannelLamp.Color = missingColor;
        app.plotSpkButton.Enable = 0;
      end
      
      % SW Options
      if isfield(app.selData, 'SW')
        if isfield(app.selData.SW, 'tSeries')
          app.swFiltLamp.Color = presentColor;
        else
          app.swFiltLamp.Color = missingColor;
        end
      else
        app.swFiltLamp.Color = missingColor;
      end
      
      % Ripple Options
      if isfield(app.selData, 'R')
        if isfield(app.selData.R, 'tSeries')
          app.rFiltLamp.Color = presentColor;
        else
          app.rFiltLamp.Color = missingColor;
        end
      else
        app.rFiltLamp.Color = missingColor;
      end
      
      % SWR - Base Detect Options
      if isfield(app.selData, 'SW') && isfield(app.selData, 'R')
        if isfield(app.selData.SW, 'baseThresh') && isfield(app.selData.R, 'baseThresh')
          app.baseDetectSWRLamp.Color = presentColor;
        else
          app.baseDetectSWRLamp.Color = missingColor;
        end
      else
        app.baseDetectSWRLamp.Color = missingColor;
      end

      % SWR - Peak Detect Options
      if isfield(app.selData, 'SWR')
        if isfield(app.selData.SWR, 'evStatus')
          app.peakDetectSWRLamp.Color = presentColor;
        else
          app.peakDetectSWRLamp.Color = missingColor;
        end
      else
        app.peakDetectSWRLamp.Color = missingColor;
      end
      
      % Theta Options
      if isfield(app.selData, 'theta')
        if isfield(app.selData.theta, 'tSeries')
          app.thetaFiltLamp.Color = presentColor;
        else
          app.thetaFiltLamp.Color = missingColor;
        end
      else
        app.thetaFiltLamp.Color = missingColor;
      end

      % Beta Options
      if isfield(app.selData, 'beta')
        if isfield(app.selData.beta, 'tSeries')
          app.betaFiltLamp.Color = presentColor;
        else
          app.betaFiltLamp.Color = missingColor;
        end
      else
        app.betaFiltLamp.Color = missingColor;
      end
      
      % Gamma Options
      if isfield(app.selData, 'gamma')
        if isfield(app.selData.gamma, 'tSeries')
          app.gammaFiltLamp.Color = presentColor;
        else
          app.gammaFiltLamp.Color = missingColor;
        end
      else
        app.gammaFiltLamp.Color = missingColor;
      end
      
      % High Gamma Options
      if isfield(app.selData, 'hgamma')
        if isfield(app.selData.hgamma, 'tSeries')
          app.hgammaFiltLamp.Color = presentColor;
        else
          app.hgammaFiltLamp.Color = missingColor;
        end
      else
        app.hgammaFiltLamp.Color = missingColor;
      end
      
      % Fast Ripple Options
      if isfield(app.selData, 'fR')
        if isfield(app.selData.fR, 'tSeries')
          app.fRFiltLamp.Color = presentColor;
        else
          app.fRFiltLamp.Color = missingColor;
        end
      else
        app.fRFiltLamp.Color = missingColor;
      end

      % Spike Options
      if isfield(app.selData, 'C')
        if isfield(app.selData.C, 'spike')
          
          % Import Spike Options
          if isfield(app.selData.C.spike, 'evStatus')
            app.importSpkLamp.Color = presentColor;
          else
            app.importSpkLamp.Color = missingColor;
          end
          
          % Type of Spike-LFP Analysis
          if isfield(app.selData.C.spike, 'nEventsA')
            app.swrSpkLamp.Color = presentColor;
          else
            app.swrSpkLamp.Color = missingColor;
          end
          if isfield(app.selData.C, 'burst')
            if isfield(app.selData.C.burst, 'nEventsA')
              app.swrBstLamp.Color = presentColor;
            else
              app.swrBstLamp.Color = missingColor;
            end
          end
          if isfield(app.selData.C.spike, 'theta') || isfield(app.selData.C.spike, 'alpha') || isfield(app.selData.C.spike, 'beta') || isfield(app.selData.C.spike, 'gamma') || isfield(app.selData.C.spike, 'hgamma')
            app.lfpSpkLamp.Color = presentColor;
          else
            app.lfpSpkLamp.Color = missingColor;
          end
          
          % Spk-SWR Options
          if isfield(app.selData.C.spike, 'nEventsA')
            if isfield(app.selData.SWR, 'evStatusStand')
              app.useSWRDurationSpkLamp.Color = missingColor;  
              app.useSWRWindowSpkLamp.Color   = presentColor;
            else
              app.useSWRDurationSpkLamp.Color = presentColor;  
              app.useSWRWindowSpkLamp.Color   = missingColor;   
            end
          else
            app.useSWRDurationSpkLamp.Color = missingColor;
            app.useSWRWindowSpkLamp.Color   = missingColor;
          end
          
        else
          app.useSWRDurationSpkLamp.Color = missingColor;
          app.useSWRWindowSpkLamp.Color   = missingColor;
        end
      else
        app.importSpkLamp.Color         = missingColor;
        app.swrSpkLamp.Color            = missingColor;
        app.lfpSpkLamp.Color            = missingColor;
        app.swrBstLamp.Color            = missingColor;
        app.useSWRDurationSpkLamp.Color = missingColor;
        app.useSWRWindowSpkLamp.Color   = missingColor;
      end
      
      % Whole-cell Options
      if isfield(app.selData, 'C')
        if isfield(app.selData.C, 'PSC')
          
          % Import PSC Options
          if isfield(app.selData.C.PSC, 'evStatusPeak')
            app.importPSCLamp.Color = presentColor;
          else
            app.importPSCLamp.Color = missingColor;
          end
          
          % Process PSCs options
          if isfield(app.selData.C.PSC, 'nEventsA')
            app.swrPSCLamp.Color = presentColor;
            if isfield(app.selData.SWR, 'evStatusStand')
              app.useSWRDurationPSCLamp.Color = missingColor;  
              app.useSWRWindowPSCLamp.Color   = presentColor;
            else
              app.useSWRDurationPSCLamp.Color = presentColor;  
              app.useSWRWindowPSCLamp.Color   = missingColor;   
            end
          else
            app.swrPSCLamp.Color = missingColor;
            app.useSWRDurationPSCLamp.Color = missingColor;
            app.useSWRWindowPSCLamp.Color   = missingColor;
          end
          
          % Calculate event matrix options
          if isfield(app.selData.C.PSC, 'swrMatrix')
            app.calcEvMatrixPSCLamp.Color = presentColor;
          else
            app.calcEvMatrixPSCLamp.Color = missingColor;
          end
          
        else
          app.importPSCLamp.Color         = missingColor; 
          app.swrPSCLamp.Color            = missingColor; 
          app.useSWRDurationPSCLamp.Color = missingColor;
          app.useSWRWindowPSCLamp.Color   = missingColor;
          app.calcEvMatrixPSCLamp.Color   = missingColor;
        end
      else
        app.importPSCLamp.Color         = missingColor;
        app.swrPSCLamp.Color            = missingColor;
        app.useSWRDurationPSCLamp.Color = missingColor;
        app.useSWRWindowPSCLamp.Color   = missingColor;
        app.calcEvMatrixPSCLamp.Color   = missingColor;
      end
      
      % Parse PSCs options
      if isfield(app.selData, 'C')
        if isfield(app.selData.C, 'SWR')
          if isfield(app.selData.C.SWR, 'PSC')
            app.parsePSCLamp.Color = presentColor;
          else
            app.parsePSCLamp.Color = missingColor;
          end
        else
          app.parsePSCLamp.Color = missingColor;
        end
      else
        app.parsePSCLamp.Color = missingColor;
      end
      

      % Ca - baseline correction Options
      if isfield(app.selData, 'Ca')
        if isfield(app.selData.Ca, 'tSeriesF')
          app.baseCorrectCaLamp.Color = presentColor;
        else
          app.baseCorrectCaLamp.Color = missingColor;
        end
      else
        app.baseCorrectCaLamp.Color = missingColor;
      end
      
      % Ca - interpolation Options
      if isfield(app.selData, 'Ca')
        if isfield(app.selData.Ca, 'tSeriesI')
          app.interpCaLamp.Color = presentColor;
        else
          app.interpCaLamp.Color = missingColor;
        end
      else
        app.interpCaLamp.Color = missingColor;
      end
      
      % Ca - peak detection Options
      if isfield(app.selData, 'Ca')
        if (isfield(app.selData.Ca, 'evStatus'))
          app.peakDetectCaLamp.Color = presentColor;
        else
          app.peakDetectCaLamp.Color = missingColor;
        end
      else
        app.peakDetectCaLamp.Color = missingColor;
      end

      % Ca - baseline detection Options
      if isfield(app.selData, 'Ca')
        if (isfield(app.selData.Ca, 'baseSD'))
          app.baseDetectCaLamp.Color = presentColor;
        else
          app.baseDetectCaLamp.Color = missingColor;
        end
      else
        app.baseDetectCaLamp.Color = missingColor;
      end
      
      % Ca - SWR Match Options
      if isfield(app.selData, 'Ca')
        if (isfield(app.selData.Ca, 'SWR'))
          app.swrCaLamp.Color = presentColor;
        else
          app.swrCaLamp.Color = missingColor;
        end
      else
        app.swrCaLamp.Color = missingColor;
      end
      
      % Plot Ca Options
      if isfield(app.selData, 'Ca')
        app.plotCaButton.Enable = 1;
        if (isfield(app.selData.Ca, 'SWR'))
          app.plotCaCorrButton.Enable = 1;
        else
          app.plotCaCorrButton.Enable = 0;
        end
      else
        app.plotCaButton.Enable = 0;
        app.plotCaCorrButton.Enable = 0;
      end
      
    end
    
    function updateParamLFP(app)
      app.param = [];
      
      if app.SingleLFPButton.Value
        app.param.fileNum = 1;
      elseif app.MultipleLFPButton.Value
        app.param.fileNum = 2;
      end
      
      if app.abfButton.Value
        app.param.fileType = 1;
      elseif app.ASCIIButton.Value
        app.param.fileType = 2;
      elseif app.matButton.Value
        app.param.fileType = 3;
      end
      
      app.param.Fs               = app.FsField.Value;
      app.param.dsFactor         = app.dsFactorField.Value;
      app.param.dsPlot           = app.dsPlotLFPField.Value;
      app.param.lfpChannel       = app.lpfChannelField.Value;
      app.param.cellOption       = app.cellCheckBox.Value;
      app.param.cellChannel      = app.cellChannelField.Value;
      
      if strcmp(app.filtTypeButtonGroup.SelectedObject.Text, 'MATLAB bandpass')
        app.param.filtType = 1;
      elseif strcmp(app.filtTypeButtonGroup.SelectedObject.Text, 'Custom gaussian')
        app.param.filtType = 2;
      end
      
      app.param.notchOption      = app.notchFiltCheckBox.Value;
      app.param.notchFreq        = app.notchFiltFreqField.Value;
      
      app.param.lfpOption        = app.lfpFiltCheckBox.Value;
      app.param.lfpLim1          = app.lfpLim1Field.Value;
      app.param.lfpLim2          = app.lfpLim2Field.Value;
      
      app.param.swrOption        = app.swrDetectCheckBox.Value;
      app.param.swOption         = app.swFiltCheckBox.Value;
      app.param.swLim1           = app.swLim1Field.Value;
      app.param.swLim2           = app.swLim2Field.Value;
      app.param.rmsPeriodSW      = app.rmsPeriodSWField.Value;
      app.param.rOption          = app.rFiltCheckBox.Value;
      app.param.rLim1            = app.rLim1Field.Value;
      app.param.rLim2            = app.rLim2Field.Value;
      app.param.rmsPeriodR       = app.rmsPeriodRField.Value;

      if ~app.baseDetectLFPCheckbox.Value
        app.param.baseDetectMethod = 0;  
      elseif strcmp(app.baseDetectLFPButtonGroup.SelectedObject.Text, 'Lower Quantile')
        app.param.baseDetectMethod = 1;
      elseif strcmp(app.baseDetectLFPButtonGroup.SelectedObject.Text, 'Gaussian Fit')
        app.param.baseDetectMethod = 2;
      end

      app.param.baseQuant        = app.baseQuantLFPField.Value;
      app.param.skewedBL         = app.skewedBLLFPCheckBox.Value;
      app.param.pkDiffMin        = app.pkDiffMinLFPField.Value;
      app.param.pkSimLim         = app.pkSimLimLFPField.Value;
      app.param.kurtosisMin      = app.kurtosisMinLFPField.Value;
      app.param.kurtosisMax      = app.kurtosisMaxLFPField.Value;
      app.param.excludeQuant     = app.excludeQuantLFPField.Value;
      app.param.plotFitHisto     = app.plotFitHistoLFPCheckBox.Value;
      
      app.param.peakDetectOption = app.peakDetectSWRCheckbox.Value;
      app.param.rmsMinEvDiff     = app.rmsMinEvDiffField.Value;
      app.param.rmsMinEvDur      = app.rmsMinEvDurField.Value;
      app.param.sdMultSW         = app.sdMultSWField.Value;
      app.param.sdBaseFactorSW   = app.sdBaseFactorSWField.Value;
      app.param.sdMultR          = app.sdMultRField.Value;
      app.param.sdBaseFactorR    = app.sdBaseFactorRField.Value;

      if app.SWAndRippleButton.Value
        app.param.swrType = 1;
      elseif app.SWOnlyButton.Value
        app.param.swrType = 2;
      elseif app.RippleOnlyButton.Value
        app.param.swrType = 3;
      end

      app.param.swrWindow        = app.swrWindowField.Value;
      app.param.expSWREvOption   = app.expSWREvCheckbox.Value;
      app.param.expSWRDataOption = app.expSWRDataCheckbox.Value;
      
      app.param.thetaOption      = app.thetaFiltCheckBox.Value;
      app.param.thetaLim1        = app.thetaLim1Field.Value;
      app.param.thetaLim2        = app.thetaLim2Field.Value;
      app.param.alphaOption      = app.alphaFiltCheckBox.Value;
      app.param.alphaLim1        = app.alphaLim1Field.Value;
      app.param.alphaLim2        = app.alphaLim2Field.Value;
      app.param.betaOption       = app.betaFiltCheckBox.Value;
      app.param.betaLim1         = app.betaLim1Field.Value;
      app.param.betaLim2         = app.betaLim2Field.Value;
      app.param.gammaOption      = app.gammaFiltCheckBox.Value;
      app.param.gammaLim1        = app.gammaLim1Field.Value;
      app.param.gammaLim2        = app.gammaLim2Field.Value;
      app.param.hgammaOption     = app.hgammaFiltCheckBox.Value;
      app.param.hgammaLim1       = app.hgammaLim1Field.Value;
      app.param.hgammaLim2       = app.hgammaLim2Field.Value;
      app.param.fROption         = app.fRFiltCheckBox.Value;
      app.param.fRLim1           = app.fRLim1Field.Value;
      app.param.fRLim2           = app.fRLim2Field.Value;
      
      app.param.spectOption      = app.spectCheckBox.Value;
      app.param.spectLim1        = app.spectLim1Field.Value;
      app.param.spectLim2        = app.spectLim2Field.Value;
      app.param.fftOption        = app.fftCheckBox.Value;
      app.param.phaseOption      = app.phaseCheckBox.Value;
      app.param.xFreqOption      = app.xFreqCheckBox.Value;
      app.param.xFreqBin         = app.xFreqBinField.Value;
      app.param.xFreqLow         = app.xFreqLowDropDown.Value;
      app.param.morlWidth        = app.morlWidthField.Value;
      app.param.nShuffle         = app.nShuffleField.Value;
      app.param.winLength        = app.winLengthField.Value;
      app.param.winOverlap       = app.winOverlapField.Value;
      
      app.param.importStimOption = app.importStimCheckBox.Value;
      app.param.expAveOption     = app.expAveCheckbox.Value;
      app.param.transposeOption  = app.transposeCheckbox.Value;
    end
    
    function updateParamCa(app)
      app.param = [];
      if strcmp(app.fileNumCaButtonGroup.SelectedObject.Text, 'Single Recording')
        app.param.fileNum   = 1;
      elseif strcmp(app.fileNumCaButtonGroup.SelectedObject.Text, 'Multiple (batch process)')
        app.param.fileNum   = 2;
      end
      
      app.param.CaFrameRateOption      = app.CaFrameRateCheckbox.Value;
      app.param.CaFrameRate            = app.CaFrameRateField.Value;
      app.param.interpOption           = app.interpCheckbox.Value;
      app.param.samplingInt            = app.samplingIntCaField.Value;
      
      if ~app.baseCorrectCaCheckbox.Value
        app.param.baseCorrectMethod = 0;  
      elseif strcmp(app.baseCorrectCaButtonGroup.SelectedObject.Text, 'Gaussian Filter')
        app.param.baseCorrectMethod = 1;
      elseif strcmp(app.baseCorrectCaButtonGroup.SelectedObject.Text, 'Smoothed Average')
        app.param.baseCorrectMethod = 2;
      end
      
      app.param.CaFiltLim1             = app.CaLim1Field.Value;
      app.param.CaFiltLim2             = app.CaLim2Field.Value;
      app.param.CaFiltOrder            = app.CaFiltOrderField.Value;
      app.param.CaFiltAlpha            = app.CaFiltAlphaField.Value;
      app.param.smoothFactor           = app.smoothFactorField.Value;
      app.param.dsPlot                 = app.dsPlotCaField.Value;
      
      app.param.cellTypeOption         = app.cellTypeOptionCheckbox.Value;
      app.param.nCellTypes             = app.nCellTypesField.Value;
      app.param.cellTypeName{1}        = app.cellType1EditField.Value;
      app.param.cellTypeName{2}        = app.cellType2EditField.Value;
      app.param.cellTypeName{3}        = app.cellType3EditField.Value;
      app.param.cellTypeName{4}        = app.cellType4EditField.Value;
      app.param.cellTypeName{5}        = app.cellType5EditField.Value;
      app.param.cellTypeName{6}        = app.cellType6EditField.Value;
      
      app.param.peakDetectCa           = app.peakDetectCaCheckbox.Value;
      
      if ~app.baseDetectCaCheckbox.Value
        app.param.baseDetectMethod = 0;  
      elseif strcmp(app.baseDetectCaButtonGroup.SelectedObject.Text, 'Lower Quantile')
        app.param.baseDetectMethod = 1;
      elseif strcmp(app.baseDetectCaButtonGroup.SelectedObject.Text, 'Gaussian Fit')
        app.param.baseDetectMethod = 2;
      end
      
      app.param.baseQuant              = app.baseQuantCaField.Value;
      app.param.skewedBL               = app.skewedBLCaCheckBox.Value;
      app.param.pkDiffMin              = app.pkDiffMinCaField.Value;
      app.param.pkSimLim               = app.pkSimLimCaField.Value;
      app.param.minBinsBL              = app.minBinsBLCaField.Value;
      app.param.nSDLim                 = app.nSDLimCaField.Value;
      app.param.kurtosisMin            = app.kurtosisMinCaField.Value;
      app.param.kurtosisMax            = app.kurtosisMaxCaField.Value;
      app.param.excludeQuant           = app.excludeQuantCaField.Value;
      app.param.weightFactor           = app.weightFactorCaField.Value;
      app.param.plotFitHisto           = app.plotFitHistoCaCheckBox.Value;

      app.param.sdMult                 = app.sdMultCaField.Value;
      app.param.sdBaseFactor           = app.sdBaseFactorCaField.Value;
      app.param.skipDetectLim          = app.skipDetectLimField.Value;
      app.param.consThreshOption       = app.consistentThreshCheckBox.Value;
      app.param.expCaEvOption          = app.expCaCheckbox.Value;
      app.param.CaFreqOption           = app.CaFreqOptionCheckbox.Value;
      app.param.swrCaOption            = app.swrCaCheckbox.Value;
      
      if strcmp(app.swrCaButtonGroup.SelectedObject.Text, 'Use detected SWR duration')
        app.param.useSWRDurationOption = 1;
        app.param.useSWRWindowOption   = 0;
      elseif strcmp(app.swrCaButtonGroup.SelectedObject.Text, 'Use standard swrWindow')
        app.param.useSWRDurationOption = 0;
        app.param.useSWRWindowOption   = 1;
      end

      app.param.swrWindow        = app.swrWindowField.Value;
      app.param.expSWREvOption   = app.expSWRCaCheckbox.Value;
      
      app.param.alignEndOption   = app.alignOptionEndButton.Value;

      app.param.stimCaOption     = app.stimCaCheckBox.Value;
      app.param.stimCaLim1       = app.stimCaLim1Field.Value;
      app.param.stimCaLim2       = app.stimCaLim2Field.Value;
      app.param.expStimEvOption  = app.expStimCaCheckbox.Value;
      app.param.expAveOption     = app.expAveCheckbox.Value;
    end

    function updateParamSpk(app)
      app.param = [];
      
      if strcmp(app.fileNumSpkButtonGroup.SelectedObject.Text, 'Single Recording')
        app.param.fileNum  = 1;
      elseif strcmp(app.fileNumSpkButtonGroup.SelectedObject.Text, 'Multiple (batch process)')
        app.param.fileNum  = 2;
      end
      app.param.dsPlot          = app.dsPlotSpkField.Value;
      
      app.param.importSpkOption = app.importSpkCheckBox.Value;
      app.param.swrSpkOption    = app.swrSpkButton.Value;
      app.param.swrBstOption    = app.swrBstCheckBox.Value;
      app.param.lfpSpkOption    = app.lfpSpkButton.Value;
      
      if strcmp(app.spkSWRButtonGroup.SelectedObject.Text, 'Use detected SWR duration')
        app.param.useSWRDurationOption = 1;
        app.param.useSWRWindowOption   = 0;
      elseif strcmp(app.spkSWRButtonGroup.SelectedObject.Text, 'Use standard swrWindow')
        app.param.useSWRDurationOption = 0;
        app.param.useSWRWindowOption   = 1;
      end
      app.param.swrWindow         = app.swrWindowField.Value;
      app.param.spkPhaseSWROption = app.spkPhaseSWRCheckbox.Value;
      app.param.expSWREvOption    = app.expSWRSpkCheckbox.Value;
      app.param.spkPhaseLFPOption = app.spkPhaseLFPCheckbox.Value;
      app.param.spkAmpLFPOption   = app.spkAmpLFPCheckbox.Value;
      app.param.sdMultPhase       = app.sdMultPhaseField.Value;
      app.param.calcPhaseStats    = app.calcPhaseStatsCheckbox.Value;
      app.param.expSpkEvOption    = app.expSpkEvCheckBox.Value;
      app.param.expBstEvOption    = app.expBstEvCheckBox.Value;
      app.param.expAveOption      = app.expAveCheckbox.Value;
    end
    
    function updateParamPSC(app)
      app.param = [];
      
      if strcmp(app.fileNumPSCButtonGroup.SelectedObject.Text, 'Single Recording')
        app.param.fileNum = 1;
      elseif strcmp(app.fileNumPSCButtonGroup.SelectedObject.Text, 'Multiple (batch process)')
        app.param.fileNum = 2;
      end
      
      if strcmp(app.pscEventPolarityButtonGroup.SelectedObject.Text, '+')
        app.param.pscEventPolarity = true;
      elseif strcmp(app.pscEventPolarityButtonGroup.SelectedObject.Text, '-')
        app.param.pscEventPolarity = false;
      end
      app.param.swrPSQOption    = app.swrPSQCheckBox.Value;
      app.param.dsPlot          = app.dsPlotPSCField.value;
            
      app.param.importPSCOption = app.importPSCCheckBox.Value;
      app.param.swrPSCOption    = app.swrPSCCheckBox.Value;
      
      if strcmp(app.swrPSCButtonGroup.SelectedObject.Text, 'Use detected SWR duration')
        app.param.useSWRDurationOption = 1;
        app.param.useSWRWindowOption   = 0;
      elseif strcmp(app.swrPSCButtonGroup.SelectedObject.Text, 'Use standard swrWindow')
        app.param.useSWRDurationOption = 0;
        app.param.useSWRWindowOption   = 1;
      end
      app.param.swrWindow      = app.swrWindowField.Value;
      app.param.parsePSCOption = app.parsePSCCheckBox.Value;
      app.param.expPSCEvOption = app.expPSCEvCheckBox.Value;
      app.param.expSWREvOption = app.expSWRPSCCheckbox.Value;
      app.param.gammaOption    = app.gammaFiltPSCCheckBox.Value;
      app.param.gammaLim1      = app.gammaLim1PSCField.Value;
      app.param.gammaLim2      = app.gammaLim2PSCField.Value;
      app.param.rOption        = app.rFiltPSCCheckBox.Value;
      app.param.rLim1          = app.rLim1PSCField.Value;
      app.param.rLim2          = app.rLim2PSCField.Value;
      app.param.spectOption    = app.spectPSCCheckBox.Value;
      app.param.spectLim1      = app.spectLim1PSCField.Value;
      app.param.spectLim2      = app.spectLim2PSCField.Value;
      app.param.expAveOption   = app.expAveCheckbox.Value;
    end
    
    function updateSWRReview(app)
      
      % No data file open:
      if isempty(app.selData)
        app.selSWREvLabel.Text = '';
        app.nSWREvLabel.Text   = '';
        app.rejectSWREvButton.Enable = 0;
        app.prevSWREvButton.Enable   = 0;
        app.nextSWREvButton.Enable   = 0;
        
      elseif isfield(app.selData, 'SWR')
        
        % Special logic for zero events:
        if isempty(app.selData.SWR.event)
          app.selEvent = 0;
          app.rejectSWREvButton.Enable = 0;
        else % Enable reject button:
          app.rejectSWREvButton.Enable = 1;
        end
        
        % Enable previous event button (if not first event)
        if app.selEvent > 1
          app.prevSWREvButton.Enable = 1;
        else
          app.prevSWREvButton.Enable = 0;
        end
        
        % Enable next event button (if not last event)
        if app.selEvent < length(app.selData.SWR.event)
          app.nextSWREvButton.Enable = 1;
        else
          app.nextSWREvButton.Enable = 0;
        end
        
        % Special logic when last event has been deleted
        if app.selEvent > length(app.selData.SWR.event)
          app.selEvent = length(app.selData.SWR.event);
        end
        
        % Update labels on UI:
        app.selSWREvLabel.Text = num2str(app.selEvent);
        app.nSWREvLabel.Text   = num2str(length(app.selData.SWR.event));
      
      end
    end
    
    function updateXFreqDropdowns(app)
      % Low Freq Dropdown
      loList = {'theta', 'alpha', 'beta', 'SW'};
      if isempty(app.data)
        loOption = logical([app.thetaFiltCheckBox.Value app.alphaFiltCheckBox.Value app.betaFiltCheckBox.Value app.swFiltCheckBox.Value]);
      else
        loOption = [or(app.thetaFiltCheckBox.Value, isfield(app.data,'theta')) or(app.alphaFiltCheckBox.Value, isfield(app.data,'alpha')) ...
          or(app.betaFiltCheckBox.Value, isfield(app.data,'beta')) or(app.swFiltCheckBox.Value, isfield(app.data,'SW'))];
      end
      loList = loList(loOption);
      app.xFreqLowDropDown.Items = loList;
      if ~isempty(loList); app.xFreqLowDropDown.Value = loList{1}; end
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      updateParamLFP(app)
      updateXFreqDropdowns(app)
      
      % If too big for screen, shrink window:
      screenSize   = get(groot,'ScreenSize');
      screenWidth  = screenSize(3);
      screenHeight = screenSize(4);
      defPos       = app.swrAnalysisUIFigure.Position;
      
      if defPos(1) + defPos(3) > screenWidth
        app.swrAnalysisUIFigure.Position(1) = round(0.5*defPos(1));
        app.swrAnalysisUIFigure.Position(3) = screenWidth - defPos(1);
      end
      
      if defPos(2) + defPos(4) > screenHeight
        app.swrAnalysisUIFigure.Position(2) = round(0.5*defPos(2));
        app.swrAnalysisUIFigure.Position(4) = screenHeight - defPos(2);
      end
      
    end

    % Value changed function: lfpFiltCheckBox
    function lfpFiltCheckBoxValueChanged(app, event)
      value = app.lfpFiltCheckBox.Value;
      if (value == 1)
        app.lfpLim1Field.Enable = 1;
        app.lfpLim2Field.Enable = 1;
      else
        app.lfpLim1Field.Enable = 0;
        app.lfpLim2Field.Enable = 0;
      end
    end

    % Value changed function: swFiltCheckBox
    function swFiltCheckBoxValueChanged(app, event)
      value = app.swFiltCheckBox.Value;
      if (value == 1)
        app.swLim1Field.Enable = 1;
        app.swLim2Field.Enable = 1;
        app.rmsPeriodSWField.Enable = 1;
      else
        app.swLim1Field.Enable = 0;
        app.swLim2Field.Enable = 0;
        app.rmsPeriodSWField.Enable = 0;
      end
      updateXFreqDropdowns(app)
    end

    % Value changed function: rFiltCheckBox
    function rFiltCheckBoxValueChanged(app, event)
      value = app.rFiltCheckBox.Value;
      if (value == 1)
        app.rLim1Field.Enable = 1;
        app.rLim2Field.Enable = 1;
        app.rmsPeriodRField.Enable = 1;
      else
        app.rLim1Field.Enable = 0;
        app.rLim2Field.Enable = 0;
        app.rmsPeriodRField.Enable = 0;
      end
    end

    % Value changed function: thetaFiltCheckBox
    function thetaFiltCheckBoxValueChanged(app, event)
      value = app.thetaFiltCheckBox.Value;
      if (value == 1)
        app.thetaLim1Field.Enable = 1;
        app.thetaLim2Field.Enable = 1;
      else
        app.thetaLim1Field.Enable = 0;
        app.thetaLim2Field.Enable = 0;
      end
      updateXFreqDropdowns(app)
    end

    % Value changed function: gammaFiltCheckBox
    function gammaFiltCheckBoxValueChanged(app, event)
      value = app.gammaFiltCheckBox.Value;
      if (value == 1)
        app.gammaLim1Field.Enable = 1;
        app.gammaLim2Field.Enable = 1;
      else
        app.gammaLim1Field.Enable = 0;
        app.gammaLim2Field.Enable = 0;
      end
    end

    % Selection changed function: fileTypeButtonGroup
    function fileTypeButtonGroupSelectionChanged(app, event)
      selectedButton = app.fileTypeButtonGroup.SelectedObject;
      if strcmp(selectedButton.Text, 'pClamp')
        app.FsField.Enable = 0;
      elseif strcmp(selectedButton.Text, 'ASCII') || strcmp(selectedButton.Text, 'Matlab')
        app.FsField.Enable = 1;
      end
    end

    % Button pushed function: analyzeLFPButton
    function analyzeLFPButtonPushed(app, event)
      updateParamLFP(app);
      if (app.param.fileNum == 1)
        [app.data, app.hand] = analyzeLFPFile([], [], app.param, [], [], [], [], [], []);
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text   = app.data.saveName;
        app.openFile2Button.Enable = 1;
        app.saveFileButton.Enable  = 1;
        app.closeFileButton.Enable = 1;
        app.selEvent = 1;
        updateSWRReview(app);
      elseif (app.param.fileNum == 2)
        analyzeLFPBatch(app.param, [], [], [], [], [], []);
      end
    end

    % Selection changed function: resultTree
    function resultTreeSelectionChanged(app, event)
      app.selNode = app.resultTree.SelectedNodes;
      selParent   = app.selNode.Parent;
      
      % Do nothing for 0th level node (filename):
      if ~isa(selParent, 'matlab.ui.container.Tree')
        % 1st level node - 1st data structure:
        if strcmp(selParent.Text, app.data.saveName)
          app.selVar  = app.data.(app.selNode.Text);
          app.selData = app.data;

        % 2nd level node - 1st data structure:
        elseif strcmp(selParent.Parent.Text, app.data.saveName)
          app.selVar = app.data.(selParent.Text).(app.selNode.Text);

        % 3rd level node - 1st data structure:
        elseif strcmp(selParent.Parent.Parent.Text, app.data.saveName)
          app.selVar = app.data.(selParent.Parent.Text).(selParent.Text).(app.selNode.Text);

        % 4th level node - 1st data structure:
        elseif strcmp(selParent.Parent.Parent.Parent.Text, app.data.saveName)
          app.selVar = app.data.(selParent.Parent.Parent.Text).(selParent.Parent.Text).(selParent.Text).(app.selNode.Text);

        % 2nd data structure (if open)
        elseif isfield(app.data2, 'saveName') 
          % 1st level node - 2nd data structure:
          if strcmp(selParent.Text, app.data2.saveName)
            app.selVar  = app.data2.(app.selNode.Text);
            app.selData = app.data2;

          % 2nd level node - 2nd data structure:
          elseif strcmp(selParent.Parent.Text, app.data2.saveName)
            app.selVar  = app.data2.(selParent.Text).(app.selNode.Text);
            app.selData = app.data2;

          % 3rd level node - 2nd data structure:
          elseif strcmp(selParent.Parent.Parent.Text, app.data2.saveName)
            app.selVar  = app.data2.(selParent.Parent.Text).(selParent.Text).(app.selNode.Text);
            app.selData = app.data2;

          % 4th level node - 2nd data structure:
          elseif strcmp(selParent.Parent.Parent.Parent.Text, app.data2.saveName)
            app.selVar  = app.data2.(selParent.Parent.Parent.Text).(selParent.Parent.Text).(selParent.Text).(app.selNode.Text);
            app.selData = app.data2;
          end
        end
        
        selVarType = class(app.selVar);
        dispVar    = app.selVar;
        selectedVarSize = size(app.selVar);
        varSize    = int2str(selectedVarSize(1)); % String to display
        for i = 2:length(selectedVarSize)
          varSize = [varSize 'x' int2str(selectedVarSize(i))];
        end
        
        % Only display up to 2dim arrays
        if (length(selectedVarSize) > 2)
          dispVar = dispVar(:,:,1);
        end
        
        % Truncate dimensions for display purposes
        if (selectedVarSize(1) > 1000) && (selectedVarSize(2) == 1) % Column Variable
          dispVar = dispVar(1:1000, :);
        elseif (selectedVarSize(1) == 1) && (selectedVarSize(2) > 1000) % Row Variable
          dispVar = dispVar(:, 1:1000);
        elseif (selectedVarSize(1) > 25) && (selectedVarSize(2) > 25) % Array Variable
          dispVar = dispVar(1:25, 1:25);
        end
        
        % Populate display
        app.varTypeTextArea.Value = [varSize ' ' selVarType];
        if isstruct(app.selVar)
          tdata = table(cellstr(app.varTypeTextArea.Value));
        elseif ischar(app.selVar)
          tdata = table(cellstr(dispVar));
        else
          tdata = table(dispVar);
        end
        app.varValueTable.Data = tdata;
        
        % Enable export if structure selected
        if strcmp(app.selNode.Text,'SWR') || strcmp(app.selNode.Text,'spike') || strcmp(app.selNode.Text,'burst') || strcmp(app.selNode.Text,'Ca')
          app.exportEventsButton.Enable = 1;
        else
          app.exportEventsButton.Enable = 0;
        end
        
      end
    end

    % Button pushed function: openFileButton
    function openFileButtonPushed(app, event)
      [fileName, pathName] = uigetfile('.mat','Open analyzed matlab file');
      dataFile = [pathName fileName];
      if ~all(dataFile); return; end
      app.data = load(dataFile);
      [app.parentPath, dataFileName, dataFileExt] = parsePath(dataFile);
      app.dataFiles = findFilesInDir(app.parentPath, '*.mat');
      app.data.saveFile = dataFile; % Update file location in case it has been moved
      app.data.saveName = [dataFileName '.' dataFileExt]; % Update in case file has been renamed
      assignin('base', 'data', app.data);
      delete(app.resultTree.Children);
      updateUITree(app, app.data);
      app.selData = app.data;
      updateDataOptions(app);
      app.dataFile1Loaded.Text = app.data.saveName;
      app.openPrevFileButton.Enable = 1;
      app.openNextFileButton.Enable = 1;
      app.openFile2Button.Enable    = 1;
      app.saveFileButton.Enable     = 1;
      app.closeFileButton.Enable    = 1;
      app.analyzeSpkButton.Enable = 1;
      app.selEvent = 1;
      updateSWRReview(app);
    end

    % Button pushed function: openFile2Button
    function openFile2ButtonPushed(app, event)
      [fileName, pathName] = uigetfile('.mat','Open 2nd analyzed matlab file for comparison plots');
      dataFile = [pathName fileName];
      if ~all(dataFile); return; end
      app.data2 = load(dataFile);
      [app.parentPath, dataFileName, dataFileExt] = parsePath(dataFile);
      app.dataFiles = findFilesInDir(app.parentPath, '*.mat');
      app.data2.saveFile = dataFile; % Update file location in case it has been moved
      app.data2.saveName = [dataFileName '.' dataFileExt]; % Update in case file has been renamed
      assignin('base', 'data2', app.data2);
      updateUITree(app, app.data2);
      app.selData = app.data2;
      updateDataOptions(app);
      app.dataFile2Loaded.Text = app.data2.saveName;
      app.openFile3Button.Enable = 1;
      app.closeFileButton.Enable = 1;
    end

    % Button pushed function: exportEventsButton
    function exportEventsButtonPushed(app, event)
      if strcmp(app.selNode.Text,'SWR')
        exportSWREvents(app.selData, app.selData.saveFile);
      elseif strcmp(app.selNode.Text,'spike')
        exportSpkEvents(app.selData, app.selData.saveFile);
      elseif strcmp(app.selNode.Text,'burst')
        exportBstEvents(app.selData, app.selData.saveFile);
      elseif strcmp(app.selNode.Text,'Ca')
        exportCaEvents(app.selData, app.selData.saveFile);
      end
    end

    % Button pushed function: plotLFPButton
    function plotLFPButtonPushed(app, event)
      updateParamLFP(app);
      combData = combDataStruct(app.data, app.data2, app.data3);
      if isempty(app.data2) && isempty(app.data3) % only one file loaded
        app.hand.lfpFig = figure('Name',['LFP analysis for ' app.data.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      elseif isempty(app.data3) % two files loaded
        app.hand.lfpFig = figure('Name',['LFP analysis for ' app.data.saveName ' and ' app.data2.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      else % three files loaded
        app.hand.lfpFig = figure('Name',['LFP analysis for ' app.data.saveName ', ' app.data2.saveName ', and ' app.data3.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      end
      app.hand = plotLFPAnalysis(combData, app.hand, app.param, app.param.dsPlot);
      assignin('base', 'hand', app.hand);
    end

    % Button pushed function: closeFileButton
    function closeFileButtonPushed(app, event)
      app.data    = [];
      app.data2   = [];
      app.data3   = [];
      app.selData = [];
      delete(app.resultTree.Children);
      updateDataOptions(app);
      app.dataFile1Loaded.Text = '';
      app.dataFile2Loaded.Text = '';
      app.dataFile3Loaded.Text = '';
      app.openPrevFileButton.Enable = 0;
      app.openNextFileButton.Enable = 0;
      app.openFile2Button.Enable    = 0;
      app.openFile3Button.Enable    = 0;
      app.saveFileButton.Enable     = 0;
      app.saveFileButton.BackgroundColor = [0.96, 0.96, 0.96];
      app.closeFileButton.Enable    = 0;
      updateSWRReview(app);
      evalin('base', 'clear data')
      evalin('base', 'clear data2')
      evalin('base', 'clear data3')
      evalin('base', 'clear hand')
    end

    % Button pushed function: reanalyzeLFPButton
    function reanalyzeLFPButtonPushed(app, event)
      updateParamLFP(app);
      app.param.reAnalyzeOption = 1;
      if (app.param.fileNum == 2) && isempty(app.data)
        analyzeLFPBatch(app.param, [], [], [], [], [], []);
      elseif ~isempty(app.data)
        [app.data, app.hand] = analyzeLFPFile(app.data, [], app.param, app.data.LFP.dataFile, [], [], [], [], []);
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text   = app.data.saveName;
        app.openFile2Button.Enable = 1;
        app.saveFileButton.Enable  = 1;
        app.closeFileButton.Enable = 1;
        app.selEvent = 1;
        updateSWRReview(app);
      end
    end

    % Value changed function: interpCheckbox
    function interpCheckboxValueChanged(app, event)
      value = app.interpCheckbox.Value;
      if (value == 1)
        app.samplingIntCaField.Enable = 1;
      else
        app.samplingIntCaField.Enable = 0;
      end
    end

    % Value changed function: peakDetectCaCheckbox
    function peakDetectCaCheckboxValueChanged(app, event)
      value  = app.peakDetectCaCheckbox.Value;
      
      if (value == 1)
        app.sdMultCaField.Enable = 1;
        app.sdBaseFactorCaField.Enable = 1;
        app.baseDetectCaCheckbox.Enable = 1;
        app.baseDetectCaCheckbox.Value  = 1;
        baseDetectCaCheckboxValueChanged(app, event);
        app.consistentThreshCheckBox.Enable = 1;
        app.skipDetectLimField.Enable = 1;
      else
        app.sdMultCaField.Enable = 0;
        app.sdBaseFactorCaField.Enable = 0;
        app.baseDetectCaCheckbox.Enable = 0;
        app.baseDetectCaCheckbox.Value  = 0;
        baseDetectCaCheckboxValueChanged(app, event);
        app.consistentThreshCheckBox.Enable = 0;
        app.skipDetectLimField.Enable = 0;
      end
    end

    % Button pushed function: analyzeCaButton
    function analyzeCaButtonPushed(app, event)
      updateParamCa(app);
      if (app.param.fileNum == 1)
        
        % Import and process file:
        if ~isempty(app.selData) && (app.param.swrCaOption || app.param.spkCaOption || app.param.stimCaOption)
          [app.data, app.hand] = processCaFile(app.selData, [], app.param, [], [], []);
        else
          [app.data, app.hand] = processCaFile([], [], app.param, [], [], []);
        end
        
        % Trim tSeries if necessary:
        if app.param.stimCaOption
          tSeries = trimCaFile(app.data.Ca.tSeries, app.data.Ca.samplingInt, app.data.LFP.samplingInt, app.data.stim.evStart, app.param);
        else
          tSeries = trimCaFile(app.data.Ca.tSeries, app.data.Ca.samplingInt, [], [], app.param);
        end
        
        % Calculate thresholds
        [app.data.Ca.baseMean, app.data.Ca.baseSD, app.data.Ca.baseThresh, app.data.Ca.peakThresh, app.hand] = calcCaThresh(tSeries, app.hand, app.param);
        
        % Analyze file:
        [app.data, app.hand] = analyzeCaFile(app.data, app.hand, app.param, app.data.saveFile, [], [], [], []);
        
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text = app.data.saveName;
        app.openFile2Button.Enable = 1;
        app.saveFileButton.Enable  = 1;
        app.closeFileButton.Enable = 1;
      elseif (app.param.fileNum == 2)
        analyzeCaBatch(app.param, [], [], [], [], [], [], [], []);
      end

    end

    % Button pushed function: plotCaButton
    function plotCaButtonPushed(app, event)
      updateParamCa(app);
      combData = combDataStruct(app.data, app.data2, app.data3);
      if isempty(app.data2) && isempty(app.data3) % only one file loaded
        app.hand.CaFig = figure('Name',['Calcium analysis for ' app.data.Ca.CaFile],'units','normalized','outerposition',[0 0.05 1 0.95]);
      elseif isempty(app.data3) % two files loaded
        app.hand.CaFig = figure('Name',['Calcium analysis for ' app.data.saveName ' and ' app.data.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      else % three files loaded
        app.hand.CaFig = figure('Name',['Calcium analysis for ' app.data.saveName ', ' app.data2.saveName ', and ' app.data3.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      end
      app.hand = plotCaAnalysis(combData, app.hand, app.param, app.param.dsPlot);
      assignin('base', 'hand', app.hand);
    end

    % Button pushed function: reanalyzeCaButton
    function reanalyzeCaButtonPushed(app, event)
      updateParamCa(app);
      app.param.reAnalyzeOption = 1;
      if (app.param.fileNum == 2) && isempty(app.data)
        analyzeCaBatch(app.param, [], [], [], [], [], []);
      elseif ~isempty(app.data)
        
        % Pre-process if options require:
        if (app.param.baseCorrectMethod > 0) || app.param.interpOption
          [app.data, app.hand] = processCaFile(app.selData, [], app.param, [], [], []);
        end
        
        % Calculate threshold if required:
        if app.param.baseDetectMethod > 0
          [app.data.Ca.baseMean, app.data.Ca.baseSD, app.data.Ca.baseThresh, app.data.Ca.peakThresh, app.hand] = calcCaThresh(app.data.Ca.tSeries, app.hand, app.param);
        end
        
        [app.data, app.hand] = analyzeCaFile(app.data, app.hand, app.param, app.data.saveFile, [], []);
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text   = app.data.saveName;
        app.openFile2Button.Enable = 1;
        app.saveFileButton.Enable  = 1;
        app.closeFileButton.Enable = 1;
      end
      
    end

    % Value changed function: peakDetectSWRCheckbox
    function peakDetectSWRCheckboxValueChanged(app, event)
      value = app.peakDetectSWRCheckbox.Value;
      if (value == 1)
        app.SWAndRippleButton.Enable = 1;
        app.SWOnlyButton.Enable = 1;
        app.RippleOnlyButton.Enable = 1;
        app.rmsMinEvDiffField.Enable = 1;
        app.rmsMinEvDurField.Enable = 1;
        app.sdMultSWField.Enable = 1;
        app.sdBaseFactorSWField.Enable = 1;
        app.sdMultRField.Enable = 1;
        app.sdBaseFactorRField.Enable = 1;
      else
        app.SWAndRippleButton.Enable = 0;
        app.SWOnlyButton.Enable = 0;
        app.RippleOnlyButton.Enable = 0;
        app.rmsMinEvDiffField.Enable = 0;
        app.rmsMinEvDurField.Enable = 0;
        app.sdMultSWField.Enable = 0;
        app.sdBaseFactorSWField.Enable = 0;
        app.sdMultRField.Enable = 0;
        app.sdBaseFactorRField.Enable = 0;
      end
    end

    % Value changed function: swrDetectCheckBox
    function swrDetectCheckBoxValueChanged(app, event)
      value = app.swrDetectCheckBox.Value;
      if (value == 1)
        % Enable fields:
        app.swFiltCheckBox.Enable = 1;
        app.rFiltCheckBox.Enable  = 1;
        app.baseDetectLFPCheckbox.Enable = 1;
        app.peakDetectSWRCheckbox.Enable = 1;
        
        % Update values:
        app.swFiltCheckBox.Value = 1;
        app.rFiltCheckBox.Value  = 1;
        app.baseDetectLFPCheckbox.Value = 1;
        app.peakDetectSWRCheckbox.Value = 1;
        app.expSWREvCheckbox.Value = 1;
        app.expSWRDataCheckbox.Value = 1;
        
        % Trigger callbacks:
        swFiltCheckBoxValueChanged(app, event)
        rFiltCheckBoxValueChanged(app, event)
        baseDetectLFPCheckboxValueChanged(app, event)
        peakDetectSWRCheckboxValueChanged(app, event)
      else
        % Update values:
        app.swFiltCheckBox.Value = 0;
        app.rFiltCheckBox.Value  = 0;
        app.baseDetectLFPCheckbox.Value = 0;
        app.peakDetectSWRCheckbox.Value = 0;
        app.expSWREvCheckbox.Value = 0;
        app.expSWRDataCheckbox.Value = 0;

        % Trigger callbacks:
        swFiltCheckBoxValueChanged(app, event)
        rFiltCheckBoxValueChanged(app, event)
        baseDetectLFPCheckboxValueChanged(app, event)
        peakDetectSWRCheckboxValueChanged(app, event)
        
        % Disable fields:
        app.swFiltCheckBox.Enable = 0;
        app.rFiltCheckBox.Enable  = 0;
        app.baseDetectLFPCheckbox.Enable = 0;
        app.peakDetectSWRCheckbox.Enable = 0; 
      end
    end

    % Value changed function: spectCheckBox
    function spectCheckBoxValueChanged(app, event)
      value = app.spectCheckBox.Value;
      if (value == 1)
        app.spectLim1Field.Enable = 1;
        app.spectLim2Field.Enable = 1;
      else
        app.spectLim1Field.Enable = 0;
        app.spectLim2Field.Enable = 0;
      end
    end

    % Value changed function: betaFiltCheckBox
    function betaFiltCheckBoxValueChanged(app, event)
      value = app.betaFiltCheckBox.Value;
      if (value == 1)
        app.betaLim1Field.Enable = 1;
        app.betaLim2Field.Enable = 1;
      else
        app.betaLim1Field.Enable = 0;
        app.betaLim2Field.Enable = 0;
      end
      updateXFreqDropdowns(app)
    end

    % Value changed function: hgammaFiltCheckBox
    function hgammaFiltCheckBoxValueChanged(app, event)
      value = app.hgammaFiltCheckBox.Value;
      if (value == 1)
        app.hgammaLim1Field.Enable = 1;
        app.hgammaLim2Field.Enable = 1;
      else
        app.hgammaLim1Field.Enable = 0;
        app.hgammaLim2Field.Enable = 0;
      end
    end

    % Button pushed function: analyzeSpkButton
    function analyzeSpkButtonPushed(app, event)
      updateParamSpk(app);
      if (app.param.fileNum == 1)
        if isempty(app.selData)
          [app.data, app.hand] = analyzeSpkFile([], [], app.param, [], [], [], [], []);
        else
          [app.data, app.hand] = analyzeSpkFile(app.selData, [], app.param, [], [], [], [], []);
        end
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text = app.data.saveName;
        app.openFile2Button.Enable = 1;
        app.saveFileButton.Enable  = 1;
        app.closeFileButton.Enable = 1;
      elseif (app.param.fileNum == 2)
        analyzeSpkBatch(app.param, [], [], [], [], [], []);
      end
    end

    % Value changed function: cellCheckBox
    function cellCheckBoxValueChanged(app, event)
      value = app.cellCheckBox.Value;
      if (value == 1)
        app.cellChannelField.Enable = 1;
        app.pscPosPolarityButton.Enable = 1;
        app.pscNegPolarityButton.Enable = 1;
      else
        app.cellChannelField.Enable = 0;
        app.pscPosPolarityButton.Enable = 0;
        app.pscNegPolarityButton.Enable = 0;
      end
    end

    % Button pushed function: plotSpkButton
    function plotSpkButtonPushed(app, event)
      updateParamSpk(app);
      combData = combDataStruct(app.data, app.data2, app.data3);
      if isempty(app.data2) && isempty(app.data3) % only one file loaded
        app.hand.spkFig = figure('Name',['Spike analysis for ' app.data.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      elseif isempty(app.data3) % two files loaded
        app.hand.spkFig = figure('Name',['Spike analysis for ' app.data.saveName ' and ' app.data2.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      else % three files loaded
        app.hand.spkFig = figure('Name',['Spike analysis for ' app.data.saveName ', ' app.data2.saveName ', and ' app.data3.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      end
      
      % Choose type of plot based on whether doing Spike-SWR coicidence analysis or Spike-LFP phase analysis
      if app.param.swrSpkOption
        app.hand = plotSpkSWRAnalysis(combData, app.hand, app.param, app.param.dsPlot);
      elseif app.param.lfpSpkOption
        app.hand = plotSpkLFPAnalysis(combData, app.hand, app.param, app.param.dsPlot);
      end
      
      assignin('base', 'hand', app.hand);
    end

    % Value changed function: swrBstCheckBox
    function swrBstCheckBoxValueChanged(app, event)
      value = app.swrBstCheckBox.Value;
      if (value == 1)
        app.expBstEvCheckBox.Enable = 1;
        app.expBstEvCheckBox.Value  = 1;
      else
        app.expBstEvCheckBox.Enable = 0;
        app.expBstEvCheckBox.Value  = 0;
      end
    end

    % Callback function
    function swrSpkCheckBoxValueChanged(app, event)
      value = app.swrSpkCheckBox.Value;
      if (value == 1)
        app.swrBstCheckBox.Enable = 1;
        app.swrBstCheckBox.Value = 1;
        app.useSWRDurationSpkButton.Enable = 1;
        app.useSWRWindowSpkButton.Enable = 1;
      else
        app.swrBstCheckBox.Enable = 0;
        app.swrBstCheckBox.Value = 0;
        app.useSWRDurationSpkButton.Enable = 0;
        app.useSWRWindowSpkButton.Enable = 0;
      end
      swrBstCheckBoxValueChanged(app, event);
    end

    % Button pushed function: openNextFileButton
    function openNextFileButtonPushed(app, event)
      currFileIndex = find(contains(app.dataFiles, app.data.saveName));
      if (currFileIndex + 1) <= length(app.dataFiles)
        dataFile = [app.parentPath app.dataFiles{currFileIndex + 1}];
        app.data = load(dataFile);
        [app.parentPath, dataFileName, dataFileExt] = parsePath(dataFile);
        app.dataFiles = findFilesInDir(app.parentPath, '*.mat');
        app.data.saveFile = dataFile; % Update file location in case it has been moved
        app.data.saveName = [dataFileName '.' dataFileExt]; % Update in case file has been renamed
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text = app.data.saveName;
        app.openPrevFileButton.Enable = 1;
        app.openNextFileButton.Enable = 1;
        app.openFile2Button.Enable    = 1;
        app.saveFileButton.Enable     = 1;
        app.closeFileButton.Enable    = 1;
        app.selEvent = 1;
        updateSWRReview(app);
      end
    end

    % Button pushed function: openPrevFileButton
    function openPrevFileButtonPushed(app, event)
      currFileIndex = find(contains(app.dataFiles, app.data.saveName));
      if (currFileIndex - 1) >= 1
        dataFile = [app.parentPath app.dataFiles{currFileIndex - 1}];
        app.data = load(dataFile);
        [app.parentPath, dataFileName, dataFileExt] = parsePath(dataFile);
        app.dataFiles = findFilesInDir(app.parentPath, '*.mat');
        app.data.saveFile = dataFile; % Update file location in case it has been moved
        app.data.saveName = [dataFileName '.' dataFileExt]; % Update in case file has been renamed
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text = app.data.saveName;
        app.openPrevFileButton.Enable = 1;
        app.openNextFileButton.Enable = 1;
        app.openFile2Button.Enable    = 1;
        app.saveFileButton.Enable     = 1;
        app.closeFileButton.Enable    = 1;
        app.selEvent = 1;
        updateSWRReview(app);
      end
    end

    % Button pushed function: prevSWREvButton
    function prevSWREvButtonPushed(app, event)
      app.selEvent = app.selEvent - 1;
      updateSWRReview(app);
      app.hand = plotLFPUpdate(app.selData, app.hand, app.param, app.selEvent);
    end

    % Button pushed function: nextSWREvButton
    function nextSWREvButtonPushed(app, event)
      app.selEvent = app.selEvent + 1;
      updateSWRReview(app);
      app.hand = plotLFPUpdate(app.selData, app.hand, app.param, app.selEvent);
    end

    % Button pushed function: rejectSWREvButton
    function rejectSWREvButtonPushed(app, event)
      app.selData = rejectSWREvent(app.selData, app.selEvent);
      updateSWRReview(app);
      assignin('base', 'data', app.selData);
      delete(app.resultTree.Children);
      updateUITree(app, app.selData);
      app.hand = plotLFPUpdate(app.selData, app.hand, app.param, app.selEvent);
      
      % Add indicator to save button that file has been edited:
      app.saveFileButton.BackgroundColor = [0.64, 0.08, 0.10];
    end

    % Button pushed function: saveFileButton
    function saveFileButtonPushed(app, event)
      updateParamLFP(app);
      
      % Export SWR event table (if selected)
      if app.param.expSWREvOption && app.param.swrOption
        if ~isfield (app.selData.SWR, 'expEvFile')
          [parPath, dataFileName, ~] = parsePath(app.selData.saveFile);
          app.selData.SWR.expEvFile = [parPath dataFileName '_swrEvents.csv'];
        end
        exportSWREvents(app.selData, app.selData.saveFile, app.selData.SWR.expEvFile);
      end
      
      % Export SWR data file (if selected)
      if app.param.expSWRDataOption && app.param.swrOption
        if ~isfield (app.selData.SWR, 'expDataFile')
          [parPath, dataFileName, ~] = parsePath(app.selData.saveFile);
          app.selData.SWR.expDataFile = [parPath dataFileName '_swrData.txt'];
        end
        exportSWRData(app.selData, app.param, app.selData.saveFile, app.selData.SWR.expDataFile);
      end
      
      % Save .mat file
      saveStruct = app.selData;
      save(app.selData.saveFile,'-struct','saveStruct');
      
      % Return to default color to indiciate no pending unsaved data.
      app.saveFileButton.BackgroundColor = [0.96, 0.96, 0.96];
    end

    % Value changed function: swrCaCheckbox
    function swrCaCheckboxValueChanged(app, event)
      value = app.swrCaCheckbox.Value;
      if (value == 1)
        app.useSWRDurationCaButton.Enable = 1;
        app.useSWRWindowCaButton.Enable   = 1;
        app.expSWRCaCheckbox.Value        = 1;
      else
        app.useSWRDurationCaButton.Enable = 0;
        app.useSWRWindowCaButton.Enable   = 0;
        app.expSWRCaCheckbox.Value        = 0;
      end
      
      value2 = app.stimCaCheckBox.Value;
      if value || value2
        app.alignOptionStartButton.Enable = 1;
        app.alignOptionEndButton.Enable   = 1;
      else
        app.alignOptionStartButton.Enable = 0;
        app.alignOptionEndButton.Enable   = 0;
      end
    end

    % Selection changed function: fileNumCaButtonGroup
    function fileNumCaButtonGroupSelectionChanged(app, event)
      selectedButton = app.fileNumCaButtonGroup.SelectedObject;
      
      if strcmp(selectedButton.Text, 'Single Recording')
        app.consistentThreshCheckBox.Enable = 0;
      elseif strcmp(selectedButton.Text, 'Multiple (batch process)')
        app.consistentThreshCheckBox.Enable = 1;
      end
    end

    % Button pushed function: openFile3Button
    function openFile3ButtonPushed(app, event)
      [fileName, pathName] = uigetfile('.mat','Open 3rd analyzed matlab file for comparison plots');
      dataFile = [pathName fileName];
      if ~all(dataFile); return; end
      app.data3 = load(dataFile);
      [app.parentPath, dataFileName, dataFileExt] = parsePath(dataFile);
      app.dataFiles = findFilesInDir(app.parentPath, '*.mat');
      app.data3.saveFile = dataFile; % Update file location in case it has been moved
      app.data3.saveName = [dataFileName '.' dataFileExt]; % Update in case file has been renamed
      assignin('base', 'data3', app.data3);
      updateUITree(app, app.data3);
      app.selData = app.data3;
      updateDataOptions(app);
      app.dataFile3Loaded.Text = app.data3.saveName;
      app.closeFileButton.Enable = 1;
    end

    % Value changed function: stimCaCheckBox
    function stimCaCheckBoxValueChanged(app, event)
      value = app.stimCaCheckBox.Value;
      
      if value == 1
        app.stimCaLim1Field.Enable   = 1;
        app.stimCaLim2Field.Enable   = 1;
        app.expStimCaCheckbox.Enable = 1;
        app.expStimCaCheckbox.Value  = 1;
      else
        app.stimCaLim1Field.Enable   = 0;
        app.stimCaLim2Field.Enable   = 0;
        app.expStimCaCheckbox.Enable = 0;
        app.expStimCaCheckbox.Value  = 0;
      end
      
      value2 = app.swrCaCheckbox.Value;
      if value || value2
        app.alignOptionStartButton.Enable = 1;
        app.alignOptionEndButton.Enable   = 1;
      else
        app.alignOptionStartButton.Enable = 0;
        app.alignOptionEndButton.Enable   = 0;
      end
      
    end

    % Selection changed function: baseDetectCaButtonGroup
    function baseDetectCaButtonGroupSelectionChanged(app, event)
      selectedButton = app.baseDetectCaButtonGroup.SelectedObject;
      if app.baseDetectCaCheckbox.Value
        if strcmp(selectedButton.Text, 'Lower Quantile')
          app.skewedBLCaCheckBox.Enable     = 0;
          app.pkDiffMinCaField.Enable       = 0;
          app.pkSimLimCaField.Enable        = 0;
          app.minBinsBLCaField.Enable       = 0;
          app.nSDLimCaField.Enable          = 0;
          app.kurtosisMinCaField.Enable     = 0;
          app.kurtosisMaxCaField.Enable     = 0;
          app.excludeQuantCaField.Enable    = 0;
          app.weightFactorCaField.Enable    = 0;
          app.plotFitHistoCaCheckBox.Enable = 0;
        elseif strcmp(selectedButton.Text, 'Gaussian Fit')
          app.skewedBLCaCheckBox.Enable     = 1;
          app.pkDiffMinCaField.Enable       = 1;
          app.pkSimLimCaField.Enable        = 1;
          app.minBinsBLCaField.Enable       = 1;
          app.nSDLimCaField.Enable          = 1;
          app.kurtosisMinCaField.Enable     = 1;
          app.kurtosisMaxCaField.Enable     = 1;
          app.excludeQuantCaField.Enable    = 1;
          app.weightFactorCaField.Enable    = 1;
          app.plotFitHistoCaCheckBox.Enable = 1;
        end
      end
    end

    % Selection changed function: baseCorrectCaButtonGroup
    function baseCorrectCaButtonGroupSelectionChanged(app, event)
      selectedButton = app.baseCorrectCaButtonGroup.SelectedObject;
      if app.baseCorrectCaCheckbox.Value
        if strcmp(selectedButton.Text, 'Gaussian Filter')
          app.CaLim1Field.Enable       = 1;
          app.CaLim2Field.Enable       = 1;
          app.CaFiltOrderField.Enable  = 1;
          app.CaFiltAlphaField.Enable  = 1;
          app.smoothFactorField.Enable = 0;
        elseif strcmp(selectedButton.Text, 'Smoothed Average')
          app.CaLim1Field.Enable       = 0;
          app.CaLim2Field.Enable       = 0;
          app.CaFiltOrderField.Enable  = 0;
          app.CaFiltAlphaField.Enable  = 0;
          app.smoothFactorField.Enable = 1;
        end
      end
    end

    % Value changed function: baseDetectCaCheckbox
    function baseDetectCaCheckboxValueChanged(app, event)
      value = app.baseDetectCaCheckbox.Value;
      if value
        app.baseDetectCaButton1.Enable    = 1;
        app.baseDetectCaButton2.Enable    = 1;
        app.baseQuantCaField.Enable       = 1;
        baseDetectCaButtonGroupSelectionChanged(app, event);
      else
        app.baseDetectCaButton1.Enable    = 0;
        app.baseDetectCaButton2.Enable    = 0;
        app.baseQuantCaField.Enable       = 0;
        app.skewedBLCaCheckBox.Enable     = 0;
        app.pkDiffMinCaField.Enable       = 0;
        app.pkSimLimCaField.Enable        = 0;
        app.minBinsBLCaField.Enable       = 0;
        app.nSDLimCaField.Enable          = 0;
        app.kurtosisMinCaField.Enable     = 0;
        app.kurtosisMaxCaField.Enable     = 0;
        app.excludeQuantCaField.Enable    = 0;
        app.weightFactorCaField.Enable    = 0;
        app.plotFitHistoCaCheckBox.Enable = 0;
      end  
    end

    % Value changed function: baseCorrectCaCheckbox
    function baseCorrectCaCheckboxValueChanged(app, event)
      value = app.baseCorrectCaCheckbox.Value;
      if value
        app.baseCorrectCaButton1.Enable = 1;
        app.baseCorrectCaButton2.Enable = 1;
        baseCorrectCaButtonGroupSelectionChanged(app, event);
      else
        app.baseCorrectCaButton1.Enable = 0;
        app.baseCorrectCaButton2.Enable = 0;
        app.CaLim1Field.Enable          = 0;
        app.CaLim2Field.Enable          = 0;
        app.CaFiltOrderField.Enable     = 0;
        app.CaFiltAlphaField.Enable     = 0;
        app.smoothFactorField.Enable    = 0;
      end 
    end

    % Button pushed function: analyzePSCButton
    function analyzePSCButtonPushed(app, event)
      updateParamPSC(app);
      if (app.param.fileNum == 1)
        if isempty(app.selData)
          [app.data, app.hand] = analyzePSCFile([], [], app.param, [], [], []);
        else
          [app.data, app.hand] = analyzePSCFile(app.selData, [], app.param, [], [], []);
        end
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text = app.data.saveName;
        app.closeFileButton.Enable = 1;
        app.openFile2Button.Enable = 1;
      elseif (app.param.fileNum == 2)
        analyzePSCBatch(app.param, [], [], [], []);
      end
    end

    % Button pushed function: plotPSCButton
    function plotPSCButtonPushed(app, event)
      updateParamPSC(app);
      combData = combDataStruct(app.data, app.data2, app.data3);
      if isempty(app.data2) && isempty(app.data3) % only one file loaded
        app.hand.pscFig = figure('Name',['PSC analysis for ' app.data.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      elseif isempty(app.data3) % two files loaded
        app.hand.pscFig = figure('Name',['PSC analysis for ' app.data.saveName ' and ' app.data2.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      else % three files loaded
        app.hand.pscFig = figure('Name',['PSC analysis for ' app.data.saveName ', ' app.data2.saveName ', and ' app.data3.saveName],'units','normalized','outerposition',[0 0.05 1 0.95]);
      end
      app.hand = plotPSCAnalysis(combData, app.hand, app.param, app.param.dsPlot);
      assignin('base', 'hand', app.hand);
    end

    % Value changed function: swrPSCCheckBox
    function swrPSCCheckBoxValueChanged(app, event)
      value = app.swrPSCCheckBox.Value;
      if (value == 1)
        app.useSWRDurationPSCButton.Enable = 1;
        app.useSWRWindowPSCButton.Enable = 1;
      else
        app.useSWRDurationPSCButton.Enable = 0;
        app.useSWRWindowPSCButton.Enable = 0;
      end
    end

    % Button pushed function: reanalyzeSpkButton
    function reanalyzeSpkButtonPushed(app, event)
      updateParamSpk(app);
      app.param.reAnalyzeOption = 1;
      if (app.param.fileNum == 2) && isempty(app.data)
        analyzeSpkBatch(app.param, [], [], [], [], [], []);
      elseif ~isempty(app.data)
        [app.data, app.hand] = analyzeSpkFile(app.data, [], app.param, app.data.saveFile, [], [], [], []);
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text   = app.data.saveName;
        app.openFile2Button.Enable = 1;
        app.saveFileButton.Enable  = 1;
        app.closeFileButton.Enable = 1;
      end
    end

    % Button pushed function: reanalyzePSCButton
    function reanalyzePSCButtonPushed(app, event)
      updateParamPSC(app);
      app.param.reAnalyzeOption = 1;
      if (app.param.fileNum == 2) && isempty(app.data)
        analyzePSCBatch(app.param, [], [], [], []);
      elseif ~isempty(app.data)
        [app.data, app.hand] = analyzePSCFile(app.data, [], app.param, app.data.saveFile, [], []);
        assignin('base', 'data', app.data);
        delete(app.resultTree.Children);
        updateUITree(app, app.data);
        app.selData = app.data;
        updateDataOptions(app);
        app.dataFile1Loaded.Text   = app.data.saveName;
        app.openFile2Button.Enable = 1;
        app.saveFileButton.Enable  = 1;
        app.closeFileButton.Enable = 1;
      end
    end

    % Value changed function: fRFiltCheckBox
    function fRFiltCheckBoxValueChanged(app, event)
      value = app.fRFiltCheckBox.Value;
      if (value == 1)
        app.fRLim1Field.Enable = 1;
        app.fRLim2Field.Enable = 1;
      else
        app.fRLim1Field.Enable = 0;
        app.fRLim2Field.Enable = 0;
      end
    end

    % Value changed function: gammaFiltPSCCheckBox
    function gammaFiltPSCCheckBoxValueChanged(app, event)
      value = app.gammaFiltPSCCheckBox.Value;
      if (value == 1)
        app.gammaLim1PSCField.Enable = 1;
        app.gammaLim2PSCField.Enable = 1;
      else
        app.gammaLim1PSCField.Enable = 0;
        app.gammaLim2PSCField.Enable = 0;
      end
    end

    % Value changed function: rFiltPSCCheckBox
    function rFiltPSCCheckBoxValueChanged(app, event)
      value = app.rFiltPSCCheckBox.Value;
      if (value == 1)
        app.rLim1PSCField.Enable = 1;
        app.rLim2PSCField.Enable = 1;
      else
        app.rLim1PSCField.Enable = 0;
        app.rLim2PSCField.Enable = 0;
      end
    end

    % Value changed function: spectPSCCheckBox
    function spectPSCCheckBoxValueChanged(app, event)
      value = app.spectPSCCheckBox.Value;
      if (value == 1)
        app.spectLim1PSCField.Enable = 1;
        app.spectLim2PSCField.Enable = 1;
      else
        app.spectLim1PSCField.Enable = 0;
        app.spectLim2PSCField.Enable = 0;
      end
    end

    % Value changed function: notchFiltCheckBox
    function notchFiltCheckBoxValueChanged(app, event)
      value = app.notchFiltCheckBox.Value;
      if (value == 1)
        app.notchFiltFreqField.Enable = 1;
      else
        app.notchFiltFreqField.Enable = 0;
      end
    end

    % Value changed function: cellTypeOptionCheckbox
    function cellTypeOptionCheckboxValueChanged(app, event)
      value = app.cellTypeOptionCheckbox.Value;
      if value
        app.nCellTypesField.Enable    = 1;
        nCellTypesFieldValueChanged(app, event)
      else
        app.nCellTypesField.Enable    = 0;
        app.cellType1EditField.Enable = 0;
        app.cellType2EditField.Enable = 0;
        app.cellType3EditField.Enable = 0;
        app.cellType4EditField.Enable = 0;
        app.cellType5EditField.Enable = 0;
        app.cellType6EditField.Enable = 0;
      end
    end

    % Selection changed function: spkAnalysisTypeButtonGroup
    function spkAnalysisTypeButtonGroupSelectionChanged(app, event)
      selectedButton = app.spkAnalysisTypeButtonGroup.SelectedObject;
      if strcmp(selectedButton.Text, 'Spike-SWR (detected events)')
        app.swrBstCheckBox.Enable = 1;
        app.swrBstCheckBox.Value  = 1;
        app.useSWRDurationSpkButton.Enable = 1;
        app.useSWRWindowSpkButton.Enable = 1;
        app.spkPhaseSWRCheckbox.Enable = 1;
        app.spkPhaseSWRCheckbox.Value = 1;
        app.expSWRSpkCheckbox.Enable = 1;
        app.expSWRSpkCheckbox.Value = 1;
        app.spkPhaseLFPCheckbox.Value = 0;
        app.spkPhaseLFPCheckbox.Enable = 0;
        app.spkAmpLFPCheckbox.Value = 0;
        app.spkAmpLFPCheckbox.Enable = 0;
      elseif strcmp(selectedButton.Text, 'Spike-LFP (entire duration of file)')
        app.swrBstCheckBox.Enable = 0;
        app.swrBstCheckBox.Value  = 0;
        app.useSWRDurationSpkButton.Enable = 0;
        app.useSWRWindowSpkButton.Enable = 0;
        app.spkPhaseSWRCheckbox.Enable = 0;
        app.spkPhaseSWRCheckbox.Value = 0;
        app.expSWRSpkCheckbox.Enable = 0;
        app.expSWRSpkCheckbox.Value = 0;
        app.spkPhaseLFPCheckbox.Value = 1;
        app.spkPhaseLFPCheckbox.Enable = 1;
        app.spkAmpLFPCheckbox.Value = 1;
        app.spkAmpLFPCheckbox.Enable = 1;
      end
      swrBstCheckBoxValueChanged(app, event)
    end

    % Value changed function: alphaFiltCheckBox
    function alphaFiltCheckBoxValueChanged(app, event)
      value = app.alphaFiltCheckBox.Value;
      if (value == 1)
        app.alphaLim1Field.Enable = 1;
        app.alphaLim2Field.Enable = 1;
      else
        app.alphaLim1Field.Enable = 0;
        app.alphaLim2Field.Enable = 0;
      end
      updateXFreqDropdowns(app)
    end

    % Value changed function: xFreqCheckBox
    function xFreqCheckBoxValueChanged(app, event)
      value = app.xFreqCheckBox.Value;
      if (value == 1)
        app.xFreqBinField.Enable    = 1;
        app.xFreqLowDropDown.Enable = 1;
        app.morlWidthField.Enable   = 1;
        app.nShuffleField.Enable    = 1;
        app.winLengthField.Enable   = 1;
        app.winOverlapField.Enable  = 1;
      else
        app.xFreqBinField.Enable    = 0;
        app.xFreqLowDropDown.Enable = 0;
        app.morlWidthField.Enable   = 0;
        app.nShuffleField.Enable    = 0;
        app.winLengthField.Enable   = 0;
        app.winOverlapField.Enable  = 0;
      end
    end

    % Value changed function: spkPhaseSWRCheckbox
    function spkPhaseSWRCheckboxValueChanged(app, event)
      value1 = app.spkPhaseSWRCheckbox.Value;
      value2 = app.spkPhaseLFPCheckbox.Value;
      if (value1 == 1) || (value2 == 1) 
        app.sdMultPhaseField.Enable = 1;
        app.calcPhaseStatsCheckbox.Enable = 1;
      else
        app.sdMultPhaseField.Enable = 0;
        app.calcPhaseStatsCheckbox.Enable = 0;
        app.calcPhaseStatsCheckbox.Value = 0;
      end
    end

    % Value changed function: spkPhaseLFPCheckbox
    function spkPhaseLFPCheckboxValueChanged(app, event)
      value1 = app.spkPhaseSWRCheckbox.Value;
      value2 = app.spkPhaseLFPCheckbox.Value;
      if (value1 == 1) || (value2 == 1) 
        app.sdMultPhaseField.Enable = 1;
        app.calcPhaseStatsCheckbox.Enable = 1;
      else
        app.sdMultPhaseField.Enable = 0;
        app.calcPhaseStatsCheckbox.Enable = 0;
        app.calcPhaseStatsCheckbox.Value = 0;
      end
    end

    % Button pushed function: plotSpecButton
    function plotSpecButtonPushed(app, event)
      updateParamLFP(app);
      combData = combDataStruct(app.data, app.data2, app.data3);
      if isempty(app.data2) && isempty(app.data3) % only one file loaded
        app.hand.specFig = figure('Name',['Spectral analysis for ' app.data.saveName],'units','normalized','outerposition',[0 0.05 0.5 0.95]);
      elseif isempty(app.data3) % two files loaded
        app.hand.specFig = figure('Name',['Spectral analysis for ' app.data.saveName ' and ' app.data2.saveName],'units','normalized','outerposition',[0 0.05 0.5 0.95]);
      else % three files loaded
        app.hand.specFig = figure('Name',['Spectral analysis for ' app.data.saveName ', ' app.data2.saveName ', and ' app.data3.saveName],'units','normalized','outerposition',[0 0.05 0.5 0.95]);
      end
      app.hand = plotSpecAnalysis(combData, app.hand, app.param);
      assignin('base', 'hand', app.hand);
    end

    % Value changed function: baseDetectLFPCheckbox
    function baseDetectLFPCheckboxValueChanged(app, event)
      value = app.baseDetectLFPCheckbox.Value;
      if value
        app.baseDetectLFPButton1.Enable    = 1;
        app.baseDetectLFPButton2.Enable    = 1;
        baseDetectLFPButtonGroupSelectionChanged(app, event);
      else
        app.baseDetectLFPButton1.Enable    = 0;
        app.baseDetectLFPButton2.Enable    = 0;
        app.baseQuantLFPField.Enable       = 0;
        app.skewedBLLFPCheckBox.Enable     = 0;
        app.pkDiffMinLFPField.Enable       = 0;
        app.pkSimLimLFPField.Enable        = 0;
        app.kurtosisMinLFPField.Enable     = 0;
        app.kurtosisMaxLFPField.Enable     = 0;
        app.excludeQuantLFPField.Enable    = 0;
        app.plotFitHistoLFPCheckBox.Enable = 0;
      end

    end

    % Selection changed function: baseDetectLFPButtonGroup
    function baseDetectLFPButtonGroupSelectionChanged(app, event)
      selectedButton = app.baseDetectLFPButtonGroup.SelectedObject;
      if app.baseDetectLFPCheckbox.Value
        if strcmp(selectedButton.Text, 'Lower Quantile')
          app.skewedBLLFPCheckBox.Enable     = 0;
          app.pkDiffMinLFPField.Enable       = 0;
          app.pkSimLimLFPField.Enable        = 0;
          app.kurtosisMinLFPField.Enable     = 0;
          app.kurtosisMaxLFPField.Enable     = 0;
          app.excludeQuantLFPField.Enable    = 0;
          app.plotFitHistoLFPCheckBox.Enable = 0;
        elseif strcmp(selectedButton.Text, 'Gaussian Fit')
          app.skewedBLLFPCheckBox.Enable     = 1;
          app.pkDiffMinLFPField.Enable       = 1;
          app.pkSimLimLFPField.Enable        = 1;
          app.kurtosisMinLFPField.Enable     = 1;
          app.kurtosisMaxLFPField.Enable     = 1;
          app.excludeQuantLFPField.Enable    = 1;
          app.plotFitHistoLFPCheckBox.Enable = 1;
        end
      end
    end

    % Value changed function: expAveCheckbox
    function expAveCheckboxValueChanged(app, event)
      value = app.expAveCheckbox.Value;
      if value
        app.transposeCheckbox.Enable = 1;
      else
        app.transposeCheckbox.Enable = 0;
      end
    end

    % Value changed function: nCellTypesField
    function nCellTypesFieldValueChanged(app, event)
      value = app.nCellTypesField.Value;
      if (value == 0)
        app.cellType1EditField.Enable = 0;
        app.cellType2EditField.Enable = 0;
        app.cellType3EditField.Enable = 0;
        app.cellType4EditField.Enable = 0;
        app.cellType5EditField.Enable = 0;
        app.cellType6EditField.Enable = 0;
      elseif(value == 1)
        app.cellType1EditField.Enable = 1;
        app.cellType2EditField.Enable = 0;
        app.cellType3EditField.Enable = 0;
        app.cellType4EditField.Enable = 0;
        app.cellType5EditField.Enable = 0;
        app.cellType6EditField.Enable = 0;
      elseif(value == 2)
        app.cellType1EditField.Enable = 1;
        app.cellType2EditField.Enable = 1;
        app.cellType3EditField.Enable = 0;
        app.cellType4EditField.Enable = 0;
        app.cellType5EditField.Enable = 0;
        app.cellType6EditField.Enable = 0;
      elseif(value == 3)
        app.cellType1EditField.Enable = 1;
        app.cellType2EditField.Enable = 1;
        app.cellType3EditField.Enable = 1;
        app.cellType4EditField.Enable = 0;
        app.cellType5EditField.Enable = 0;
        app.cellType6EditField.Enable = 0;
      elseif(value == 4)
        app.cellType1EditField.Enable = 1;
        app.cellType2EditField.Enable = 1;
        app.cellType3EditField.Enable = 1;
        app.cellType4EditField.Enable = 1;
        app.cellType5EditField.Enable = 0;
        app.cellType6EditField.Enable = 0;
      elseif(value == 5)
        app.cellType1EditField.Enable = 1;
        app.cellType2EditField.Enable = 1;
        app.cellType3EditField.Enable = 1;
        app.cellType4EditField.Enable = 1;
        app.cellType5EditField.Enable = 1;
        app.cellType6EditField.Enable = 0;
      elseif(value == 6)
        app.cellType1EditField.Enable = 1;
        app.cellType2EditField.Enable = 1;
        app.cellType3EditField.Enable = 1;
        app.cellType4EditField.Enable = 1;
        app.cellType5EditField.Enable = 1;
        app.cellType6EditField.Enable = 1;
      end
    end

    % Button pushed function: plotCaCorrButton
    function plotCaCorrButtonPushed(app, event)
      updateParamCa(app);
      app.hand = plotCaCorr(app.data, app.hand, app.param);
      assignin('base', 'hand', app.hand);
    end

    % Value changed function: CaFrameRateCheckbox
    function CaFrameRateCheckboxValueChanged(app, event)
      value = app.CaFrameRateCheckbox.Value;
      if (value == 1)
        app.CaFrameRateField.Enable = 1;
      else
        app.CaFrameRateField.Enable = 0;
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.swrAnalysisUIFigure = uifigure('Visible', 'off');
      app.swrAnalysisUIFigure.Name = 'SWR Analysis';
      app.TabGroup = uitabgroup(app.swrAnalysisUIFigure);
      app.LFPTab = uitab(app.TabGroup);
      app.LFPTab.Title = 'LFP';
      app.fileNumLFPButtonGroup = uibuttongroup(app.LFPTab);
      app.fileNumLFPButtonGroup.Title = 'Number of Recordings';
      app.SingleLFPButton = uiradiobutton(app.fileNumLFPButtonGroup);
      app.SingleLFPButton.Text = 'Single Recording';
      app.SingleLFPButton.Value = true;
      app.MultipleLFPButton = uiradiobutton(app.fileNumLFPButtonGroup);
      app.MultipleLFPButton.Text = 'Multiple (batch process)';
      app.fileTypeButtonGroup = uibuttongroup(app.LFPTab);
      app.fileTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @fileTypeButtonGroupSelectionChanged, true);
      app.fileTypeButtonGroup.Title = 'File Type';
      app.abfButton = uiradiobutton(app.fileTypeButtonGroup);
      app.abfButton.Text = 'pClamp';
      app.abfButton.Value = true;
      app.ASCIIButton = uiradiobutton(app.fileTypeButtonGroup);
      app.ASCIIButton.Text = 'ASCII';
      app.matButton = uiradiobutton(app.fileTypeButtonGroup);
      app.matButton.Text = 'Matlab';
      app.lfpFiltCheckBox = uicheckbox(app.LFPTab);
      app.lfpFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @lfpFiltCheckBoxValueChanged, true);
      app.lfpFiltCheckBox.Text = 'LFP Filter';
      app.lfpFiltCheckBox.Value = true;
      app.lfpLim1Label = uilabel(app.LFPTab);
      app.lfpLim1Label.Text = 'Lower:';
      app.lfpLim1Field = uieditfield(app.LFPTab, 'numeric');
      app.lfpLim1Field.Value = 1;
      app.lfpLim2Label = uilabel(app.LFPTab);
      app.lfpLim2Label.Text = 'Upper:';
      app.lfpLim2Field = uieditfield(app.LFPTab, 'numeric');
      app.lfpLim2Field.Value = 1000;
      app.SamplingFrequencyLabel = uilabel(app.LFPTab);
      app.SamplingFrequencyLabel.Text = 'Sampling Frequency:';
      app.FsField = uieditfield(app.LFPTab, 'numeric');
      app.FsField.Value = 3000;
      app.LFPChannelLabel = uilabel(app.LFPTab);
      app.LFPChannelLabel.Text = 'LFP Channel:';
      app.lpfChannelField = uieditfield(app.LFPTab, 'numeric');
      app.lpfChannelField.Value = 1;
      app.DownsampleDataLabel = uilabel(app.LFPTab);
      app.DownsampleDataLabel.Text = 'Downsample Data:';
      app.dsFactorField = uieditfield(app.LFPTab, 'numeric');
      app.dsFactorField.Value = 1;
      app.lfpFiltLamp = uilamp(app.LFPTab);
      app.analyzeLFPButton = uibutton(app.LFPTab, 'push');
      app.analyzeLFPButton.ButtonPushedFcn = createCallbackFcn(app, @analyzeLFPButtonPushed, true);
      app.analyzeLFPButton.Text = 'Analyze LFP';
      app.plotLFPButton = uibutton(app.LFPTab, 'push');
      app.plotLFPButton.ButtonPushedFcn = createCallbackFcn(app, @plotLFPButtonPushed, true);
      app.plotLFPButton.Enable = 'off';
      app.plotLFPButton.Text = 'Plot LFP Analysis';
      app.reanalyzeLFPButton = uibutton(app.LFPTab, 'push');
      app.reanalyzeLFPButton.ButtonPushedFcn = createCallbackFcn(app, @reanalyzeLFPButtonPushed, true);
      app.reanalyzeLFPButton.Text = 'Re-Analyze LFP';
      app.HzLabel = uilabel(app.LFPTab);
      app.HzLabel.Text = 'Hz';
      app.SWRDetectionPanel = uipanel(app.LFPTab);
      app.SWRDetectionPanel.Title = 'SWR Detection';
      app.swFiltCheckBox = uicheckbox(app.SWRDetectionPanel);
      app.swFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @swFiltCheckBoxValueChanged, true);
      app.swFiltCheckBox.Text = 'SW Filter';
      app.swFiltCheckBox.Value = true;
      app.rFiltCheckBox = uicheckbox(app.SWRDetectionPanel);
      app.rFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @rFiltCheckBoxValueChanged, true);
      app.rFiltCheckBox.Text = 'Ripple Filter';
      app.rFiltCheckBox.Value = true;
      app.swFiltLamp = uilamp(app.SWRDetectionPanel);
      app.rFiltLamp = uilamp(app.SWRDetectionPanel);
      app.expSWREvCheckbox = uicheckbox(app.SWRDetectionPanel);
      app.expSWREvCheckbox.Text = 'Export SWR event files';
      app.expSWREvCheckbox.Value = true;
      app.msLabel_2 = uilabel(app.SWRDetectionPanel);
      app.msLabel_2.Text = 'ms';
      app.msLabel_3 = uilabel(app.SWRDetectionPanel);
      app.msLabel_3.Text = 'ms';
      app.HzLabel_6 = uilabel(app.SWRDetectionPanel);
      app.HzLabel_6.Text = 'Hz';
      app.HzLabel_7 = uilabel(app.SWRDetectionPanel);
      app.HzLabel_7.Text = 'Hz';
      app.expSWRDataCheckbox = uicheckbox(app.SWRDetectionPanel);
      app.expSWRDataCheckbox.Text = 'Export SWR data files';
      app.expSWRDataCheckbox.Value = true;
      app.swLim1Label = uilabel(app.SWRDetectionPanel);
      app.swLim1Label.Text = 'Lower:';
      app.swLim1Field = uieditfield(app.SWRDetectionPanel, 'numeric');
      app.swLim1Field.Value = 1;
      app.swLim2Label = uilabel(app.SWRDetectionPanel);
      app.swLim2Label.Text = 'Upper:';
      app.swLim2Field = uieditfield(app.SWRDetectionPanel, 'numeric');
      app.swLim2Field.Value = 30;
      app.rLim1Label = uilabel(app.SWRDetectionPanel);
      app.rLim1Label.Text = 'Lower:';
      app.rLim1Field = uieditfield(app.SWRDetectionPanel, 'numeric');
      app.rLim1Field.Value = 120;
      app.rLim2Label = uilabel(app.SWRDetectionPanel);
      app.rLim2Label.Text = 'Upper:';
      app.rLim2Field = uieditfield(app.SWRDetectionPanel, 'numeric');
      app.rLim2Field.Value = 220;
      app.RMSPeriodLabel = uilabel(app.SWRDetectionPanel);
      app.RMSPeriodLabel.Text = 'RMS Period:';
      app.rmsPeriodSWField = uieditfield(app.SWRDetectionPanel, 'numeric');
      app.rmsPeriodSWField.Value = 30;
      app.SWRWindowLabel = uilabel(app.SWRDetectionPanel);
      app.SWRWindowLabel.Text = 'SWR Window:';
      app.swrWindowField = uieditfield(app.SWRDetectionPanel, 'numeric');
      app.swrWindowField.Value = 100;
      app.SWRReviewPanel = uipanel(app.SWRDetectionPanel);
      app.SWRReviewPanel.Title = 'SWR Review';
      app.rejectSWREvButton = uibutton(app.SWRReviewPanel, 'push');
      app.rejectSWREvButton.ButtonPushedFcn = createCallbackFcn(app, @rejectSWREvButtonPushed, true);
      app.rejectSWREvButton.Enable = 'off';
      app.rejectSWREvButton.Text = 'Reject Event';
      app.prevSWREvButton = uibutton(app.SWRReviewPanel, 'push');
      app.prevSWREvButton.ButtonPushedFcn = createCallbackFcn(app, @prevSWREvButtonPushed, true);
      app.prevSWREvButton.Enable = 'off';
      app.prevSWREvButton.Text = '<';
      app.nextSWREvButton = uibutton(app.SWRReviewPanel, 'push');
      app.nextSWREvButton.ButtonPushedFcn = createCallbackFcn(app, @nextSWREvButtonPushed, true);
      app.nextSWREvButton.Enable = 'off';
      app.nextSWREvButton.Text = '>';
      app.selSWREvLabel = uilabel(app.SWRReviewPanel);
      app.selSWREvLabel.Enable = 'off';
      app.selSWREvLabel.Text = '';
      app.textLabel = uilabel(app.SWRReviewPanel);
      app.textLabel.Enable = 'off';
      app.textLabel.Text = 'of';
      app.nSWREvLabel = uilabel(app.SWRReviewPanel);
      app.nSWREvLabel.Enable = 'off';
      app.nSWREvLabel.Text = '';
      app.baseDetectLFPButtonGroup = uibuttongroup(app.SWRDetectionPanel);
      app.baseDetectLFPButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @baseDetectLFPButtonGroupSelectionChanged, true);
      app.baseDetectLFPButtonGroup.Title = ' ';
      app.baseDetectLFPButton1 = uiradiobutton(app.baseDetectLFPButtonGroup);
      app.baseDetectLFPButton1.Text = 'Lower Quantile';
      app.baseDetectLFPButton2 = uiradiobutton(app.baseDetectLFPButtonGroup);
      app.baseDetectLFPButton2.Text = 'Gaussian Fit';
      app.baseDetectLFPButton2.Value = true;
      app.baseQuantLFPField = uieditfield(app.baseDetectLFPButtonGroup, 'numeric');
      app.baseQuantLFPField.ValueDisplayFormat = '%.2f';
      app.baseQuantLFPField.Value = 0.8;
      app.FracDiffbetweenPeaksLabel = uilabel(app.baseDetectLFPButtonGroup);
      app.FracDiffbetweenPeaksLabel.Text = 'Frac. Diff. between Peaks:  ';
      app.pkDiffMinLFPField = uieditfield(app.baseDetectLFPButtonGroup, 'numeric');
      app.PeakSimilarityLimitLabel_2 = uilabel(app.baseDetectLFPButtonGroup);
      app.PeakSimilarityLimitLabel_2.Text = 'Peak Similarity Limit:  ';
      app.pkSimLimLFPField = uieditfield(app.baseDetectLFPButtonGroup, 'numeric');
      app.pkSimLimLFPField.Value = 2;
      app.KurtosisMinLabel_2 = uilabel(app.baseDetectLFPButtonGroup);
      app.KurtosisMinLabel_2.Text = 'Kurtosis Min:';
      app.kurtosisMinLFPField = uieditfield(app.baseDetectLFPButtonGroup, 'numeric');
      app.KurtosisMaxLabel_2 = uilabel(app.baseDetectLFPButtonGroup);
      app.KurtosisMaxLabel_2.Text = 'Kurtosis Max:';
      app.kurtosisMaxLFPField = uieditfield(app.baseDetectLFPButtonGroup, 'numeric');
      app.kurtosisMaxLFPField.Value = 5;
      app.ExclusionQuantileLabel_2 = uilabel(app.baseDetectLFPButtonGroup);
      app.ExclusionQuantileLabel_2.Text = 'Exclusion Quantile:';
      app.excludeQuantLFPField = uieditfield(app.baseDetectLFPButtonGroup, 'numeric');
      app.excludeQuantLFPField.Value = 0.9;
      app.plotFitHistoLFPCheckBox = uicheckbox(app.baseDetectLFPButtonGroup);
      app.plotFitHistoLFPCheckBox.Text = 'plot Fit Histograms';
      app.skewedBLLFPCheckBox = uicheckbox(app.baseDetectLFPButtonGroup);
      app.skewedBLLFPCheckBox.Text = 'Skewed BL';
      app.skewedBLLFPCheckBox.Value = true;
      app.msLabel_4 = uilabel(app.SWRDetectionPanel);
      app.msLabel_4.Text = 'ms';
      app.RMSPeriodLabel_2 = uilabel(app.SWRDetectionPanel);
      app.RMSPeriodLabel_2.Text = 'RMS Period:';
      app.rmsPeriodRField = uieditfield(app.SWRDetectionPanel, 'numeric');
      app.rmsPeriodRField.Value = 5;
      app.swrTypeButtonGroup = uibuttongroup(app.SWRDetectionPanel);
      app.swrTypeButtonGroup.Title = ' ';
      app.SWAndRippleButton = uiradiobutton(app.swrTypeButtonGroup);
      app.SWAndRippleButton.Text = 'Concurrent SW and Ripple';
      app.SWOnlyButton = uiradiobutton(app.swrTypeButtonGroup);
      app.SWOnlyButton.Text = 'SW Only';
      app.SWOnlyButton.Value = true;
      app.RippleOnlyButton = uiradiobutton(app.swrTypeButtonGroup);
      app.RippleOnlyButton.Text = 'Ripple Only';
      app.SDMultThreshLabel = uilabel(app.swrTypeButtonGroup);
      app.SDMultThreshLabel.Text = 'SD Mult. Thresh:';
      app.sdMultSWField = uieditfield(app.swrTypeButtonGroup, 'numeric');
      app.sdMultSWField.Value = 6;
      app.BaseFactorLabel = uilabel(app.swrTypeButtonGroup);
      app.BaseFactorLabel.Text = 'Base Factor:';
      app.sdBaseFactorSWField = uieditfield(app.swrTypeButtonGroup, 'numeric');
      app.sdBaseFactorSWField.ValueDisplayFormat = '%11.3g';
      app.sdBaseFactorSWField.Value = 0.666666666666667;
      app.msLabel_5 = uilabel(app.swrTypeButtonGroup);
      app.msLabel_5.Text = 'ms';
      app.MinIEILabel = uilabel(app.swrTypeButtonGroup);
      app.MinIEILabel.Text = 'Min IEI: ';
      app.rmsMinEvDiffField = uieditfield(app.swrTypeButtonGroup, 'numeric');
      app.rmsMinEvDiffField.Value = 150;
      app.SDMultThreshLabel_2 = uilabel(app.swrTypeButtonGroup);
      app.SDMultThreshLabel_2.Text = 'SD Mult. Thresh:';
      app.sdMultRField = uieditfield(app.swrTypeButtonGroup, 'numeric');
      app.sdMultRField.Value = 4;
      app.BaseFactorLabel_2 = uilabel(app.swrTypeButtonGroup);
      app.BaseFactorLabel_2.Text = 'Base Factor:';
      app.sdBaseFactorRField = uieditfield(app.swrTypeButtonGroup, 'numeric');
      app.sdBaseFactorRField.ValueDisplayFormat = '%11.3g';
      app.sdBaseFactorRField.Value = 0.75;
      app.MinDurLabel = uilabel(app.swrTypeButtonGroup);
      app.MinDurLabel.Text = 'Min Dur: ';
      app.rmsMinEvDurField = uieditfield(app.swrTypeButtonGroup, 'numeric');
      app.rmsMinEvDurField.Value = 25;
      app.msLabel_6 = uilabel(app.swrTypeButtonGroup);
      app.msLabel_6.Text = 'ms';
      app.baseDetectLFPCheckbox = uicheckbox(app.SWRDetectionPanel);
      app.baseDetectLFPCheckbox.ValueChangedFcn = createCallbackFcn(app, @baseDetectLFPCheckboxValueChanged, true);
      app.baseDetectLFPCheckbox.Text = 'Baseline Detection';
      app.baseDetectLFPCheckbox.Value = true;
      app.peakDetectSWRCheckbox = uicheckbox(app.SWRDetectionPanel);
      app.peakDetectSWRCheckbox.ValueChangedFcn = createCallbackFcn(app, @peakDetectSWRCheckboxValueChanged, true);
      app.peakDetectSWRCheckbox.Text = 'Peak Detection';
      app.peakDetectSWRCheckbox.Value = true;
      app.baseDetectSWRLamp = uilamp(app.SWRDetectionPanel);
      app.peakDetectSWRLamp = uilamp(app.SWRDetectionPanel);
      app.OtherFiltersPanel = uipanel(app.LFPTab);
      app.OtherFiltersPanel.Title = 'Other Filters';
      app.thetaFiltCheckBox = uicheckbox(app.OtherFiltersPanel);
      app.thetaFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @thetaFiltCheckBoxValueChanged, true);
      app.thetaFiltCheckBox.Text = 'Theta:';
      app.gammaFiltCheckBox = uicheckbox(app.OtherFiltersPanel);
      app.gammaFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @gammaFiltCheckBoxValueChanged, true);
      app.gammaFiltCheckBox.Text = 'Gamma:';
      app.gammaFiltCheckBox.Value = true;
      app.thetaFiltLamp = uilamp(app.OtherFiltersPanel);
      app.gammaFiltLamp = uilamp(app.OtherFiltersPanel);
      app.thetaLim1Field = uieditfield(app.OtherFiltersPanel, 'numeric');
      app.thetaLim1Field.Enable = 'off';
      app.thetaLim1Field.Value = 4;
      app.thetaLim2Field = uieditfield(app.OtherFiltersPanel, 'numeric');
      app.thetaLim2Field.Enable = 'off';
      app.thetaLim2Field.Value = 8;
      app.gammaLim1Field = uieditfield(app.OtherFiltersPanel, 'numeric');
      app.gammaLim1Field.Value = 20;
      app.gammaLim2Field = uieditfield(app.OtherFiltersPanel, 'numeric');
      app.gammaLim2Field.Value = 55;
      app.hgammaFiltCheckBox = uicheckbox(app.OtherFiltersPanel);
      app.hgammaFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @hgammaFiltCheckBoxValueChanged, true);
      app.hgammaFiltCheckBox.Text = 'High Gamma:';
      app.hgammaFiltCheckBox.Value = true;
      app.hgammaFiltLamp = uilamp(app.OtherFiltersPanel);
      app.hgammaLim1Field = uieditfield(app.OtherFiltersPanel, 'numeric');
      app.hgammaLim1Field.Value = 65;
      app.hgammaLim2Field = uieditfield(app.OtherFiltersPanel, 'numeric');
      app.hgammaLim2Field.Value = 85;
      app.betaFiltCheckBox = uicheckbox(app.OtherFiltersPanel);
      app.betaFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @betaFiltCheckBoxValueChanged, true);
      app.betaFiltCheckBox.Text = 'Beta:';
      app.betaFiltLamp = uilamp(app.OtherFiltersPanel);
      app.betaLim1Field = uieditfield(app.OtherFiltersPanel, 'numeric');
      app.betaLim1Field.Enable = 'off';
      app.betaLim1Field.Value = 12;
      app.betaLim2Field = uieditfield(app.OtherFiltersPanel, 'numeric');
      app.betaLim2Field.Enable = 'off';
      app.betaLim2Field.Value = 20;
      app.HzLabel_2 = uilabel(app.OtherFiltersPanel);
      app.HzLabel_2.Text = 'Hz';
      app.HzLabel_3 = uilabel(app.OtherFiltersPanel);
      app.HzLabel_3.Text = 'Hz';
      app.HzLabel_4 = uilabel(app.OtherFiltersPanel);
      app.HzLabel_4.Text = 'Hz';
      app.HzLabel_5 = uilabel(app.OtherFiltersPanel);
      app.HzLabel_5.Text = 'Hz';
      app.fRFiltCheckBox = uicheckbox(app.OtherFiltersPanel);
      app.fRFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @fRFiltCheckBoxValueChanged, true);
      app.fRFiltCheckBox.Text = 'Fast Ripple:';
      app.fRFiltCheckBox.Value = true;
      app.fRFiltLamp = uilamp(app.OtherFiltersPanel);
      app.HzLabel_14 = uilabel(app.OtherFiltersPanel);
      app.HzLabel_14.Text = 'Hz';
      app.fRLim1Field = uieditfield(app.OtherFiltersPanel, 'numeric');
      app.fRLim1Field.Value = 250;
      app.fRLim2Field = uieditfield(app.OtherFiltersPanel, 'numeric');
      app.fRLim2Field.Value = 500;
      app.alphaFiltCheckBox = uicheckbox(app.OtherFiltersPanel);
      app.alphaFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @alphaFiltCheckBoxValueChanged, true);
      app.alphaFiltCheckBox.Text = 'Alpha:';
      app.alphaFiltLamp = uilamp(app.OtherFiltersPanel);
      app.HzLabel_17 = uilabel(app.OtherFiltersPanel);
      app.HzLabel_17.Text = 'Hz';
      app.alphaLim1Field = uieditfield(app.OtherFiltersPanel, 'numeric');
      app.alphaLim1Field.Enable = 'off';
      app.alphaLim1Field.Value = 8;
      app.alphaLim2Field = uieditfield(app.OtherFiltersPanel, 'numeric');
      app.alphaLim2Field.Enable = 'off';
      app.alphaLim2Field.Value = 12;
      app.toLabeltheta = uilabel(app.OtherFiltersPanel);
      app.toLabeltheta.Text = 'to';
      app.toLabelAlpha = uilabel(app.OtherFiltersPanel);
      app.toLabelAlpha.Text = 'to';
      app.toLabelBeta = uilabel(app.OtherFiltersPanel);
      app.toLabelBeta.Text = 'to';
      app.toLabelGamma = uilabel(app.OtherFiltersPanel);
      app.toLabelGamma.Text = 'to';
      app.toLabelHGamma = uilabel(app.OtherFiltersPanel);
      app.toLabelHGamma.Text = 'to';
      app.toLabelFRipple = uilabel(app.OtherFiltersPanel);
      app.toLabelFRipple.Text = 'to';
      app.swrDetectCheckBox = uicheckbox(app.LFPTab);
      app.swrDetectCheckBox.ValueChangedFcn = createCallbackFcn(app, @swrDetectCheckBoxValueChanged, true);
      app.swrDetectCheckBox.Text = 'SWR Detection';
      app.swrDetectCheckBox.Value = true;
      app.cellCheckBox = uicheckbox(app.LFPTab);
      app.cellCheckBox.ValueChangedFcn = createCallbackFcn(app, @cellCheckBoxValueChanged, true);
      app.cellCheckBox.Text = 'Include 2nd cell channel';
      app.CellChannelLabel = uilabel(app.LFPTab);
      app.CellChannelLabel.Text = 'Cell Channel:';
      app.cellChannelField = uieditfield(app.LFPTab, 'numeric');
      app.cellChannelField.Enable = 'off';
      app.cellChannelField.Value = 2;
      app.cellChannelLamp = uilamp(app.LFPTab);
      app.lfpChannelLamp = uilamp(app.LFPTab);
      app.notchFiltCheckBox = uicheckbox(app.LFPTab);
      app.notchFiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @notchFiltCheckBoxValueChanged, true);
      app.notchFiltCheckBox.Text = 'Notch filter:';
      app.notchFiltCheckBox.Value = true;
      app.notchFiltFreqField = uieditfield(app.LFPTab, 'numeric');
      app.notchFiltFreqField.Editable = 'off';
      app.notchFiltFreqField.Value = 60;
      app.HzLabel_16 = uilabel(app.LFPTab);
      app.HzLabel_16.Text = 'Hz';
      app.importStimCheckBox = uicheckbox(app.LFPTab);
      app.importStimCheckBox.Text = 'Import Stimulation Peaks and LFP Responses';
      app.SpectralAnalysisPanel = uipanel(app.LFPTab);
      app.SpectralAnalysisPanel.Title = 'Spectral Analysis';
      app.spectCheckBox = uicheckbox(app.SpectralAnalysisPanel);
      app.spectCheckBox.ValueChangedFcn = createCallbackFcn(app, @spectCheckBoxValueChanged, true);
      app.spectCheckBox.Text = 'Spectrogram:';
      app.spectCheckBox.Value = true;
      app.spectLamp = uilamp(app.SpectralAnalysisPanel);
      app.HzLabel_8 = uilabel(app.SpectralAnalysisPanel);
      app.HzLabel_8.Text = 'Hz';
      app.spectLim1Field = uieditfield(app.SpectralAnalysisPanel, 'numeric');
      app.spectLim1Field.Value = 1;
      app.spectLim2Field = uieditfield(app.SpectralAnalysisPanel, 'numeric');
      app.spectLim2Field.Value = 250;
      app.xFreqLowLabel = uilabel(app.SpectralAnalysisPanel);
      app.xFreqLowLabel.Text = 'Low:';
      app.xFreqLowDropDown = uidropdown(app.SpectralAnalysisPanel);
      app.xFreqLowDropDown.Items = {'theta', 'alpha', 'beta', 'SW'};
      app.xFreqLowDropDown.Enable = 'off';
      app.xFreqLowDropDown.Value = 'theta';
      app.fftCheckBox = uicheckbox(app.SpectralAnalysisPanel);
      app.fftCheckBox.Text = 'FFT';
      app.fftCheckBox.Value = true;
      app.phaseCheckBox = uicheckbox(app.SpectralAnalysisPanel);
      app.phaseCheckBox.Text = 'Phase Interpolation';
      app.phaseCheckBox.Value = true;
      app.xFreqCheckBox = uicheckbox(app.SpectralAnalysisPanel);
      app.xFreqCheckBox.ValueChangedFcn = createCallbackFcn(app, @xFreqCheckBoxValueChanged, true);
      app.xFreqCheckBox.Text = 'Phase-Amplitude Coupling';
      app.fftLamp = uilamp(app.SpectralAnalysisPanel);
      app.xFreqLamp = uilamp(app.SpectralAnalysisPanel);
      app.WidthLabel = uilabel(app.SpectralAnalysisPanel);
      app.WidthLabel.Text = 'Width:';
      app.morlWidthField = uieditfield(app.SpectralAnalysisPanel, 'numeric');
      app.morlWidthField.Enable = 'off';
      app.morlWidthField.Value = 7;
      app.WindowLabel = uilabel(app.SpectralAnalysisPanel);
      app.WindowLabel.Text = 'Window:';
      app.winLengthField = uieditfield(app.SpectralAnalysisPanel, 'numeric');
      app.winLengthField.Enable = 'off';
      app.winLengthField.Value = 0.5;
      app.OverlapLabel = uilabel(app.SpectralAnalysisPanel);
      app.OverlapLabel.Text = 'Overlap:';
      app.winOverlapField = uieditfield(app.SpectralAnalysisPanel, 'numeric');
      app.winOverlapField.Enable = 'off';
      app.winOverlapField.Value = 0.2;
      app.sLabel_2 = uilabel(app.SpectralAnalysisPanel);
      app.sLabel_2.Text = 's';
      app.sLabel_3 = uilabel(app.SpectralAnalysisPanel);
      app.sLabel_3.Text = 's';
      app.HzLabel_18 = uilabel(app.SpectralAnalysisPanel);
      app.HzLabel_18.Text = 'Hz';
      app.BinLabel = uilabel(app.SpectralAnalysisPanel);
      app.BinLabel.Text = 'Bin:';
      app.xFreqBinField = uieditfield(app.SpectralAnalysisPanel, 'numeric');
      app.xFreqBinField.Enable = 'off';
      app.xFreqBinField.Value = 5;
      app.toLabel_10 = uilabel(app.SpectralAnalysisPanel);
      app.toLabel_10.Text = 'to';
      app.nShuffleLabel = uilabel(app.SpectralAnalysisPanel);
      app.nShuffleLabel.Text = 'nShuffle:';
      app.nShuffleField = uieditfield(app.SpectralAnalysisPanel, 'numeric');
      app.nShuffleField.Enable = 'off';
      app.nShuffleField.Value = 200;
      app.DownsamplePlotLabel = uilabel(app.LFPTab);
      app.DownsamplePlotLabel.Text = 'Downsample Plot:';
      app.dsPlotLFPField = uieditfield(app.LFPTab, 'numeric');
      app.dsPlotLFPField.Value = 4;
      app.plotSpecButton = uibutton(app.LFPTab, 'push');
      app.plotSpecButton.ButtonPushedFcn = createCallbackFcn(app, @plotSpecButtonPushed, true);
      app.plotSpecButton.Enable = 'off';
      app.plotSpecButton.Text = 'Plot Spec. Analysis';
      app.filtTypeButtonGroup = uibuttongroup(app.LFPTab);
      app.filtTypeButtonGroup.Title = 'Filter Method';
      app.MATLABbandpassButton = uiradiobutton(app.filtTypeButtonGroup);
      app.MATLABbandpassButton.Text = 'MATLAB bandpass';
      app.MATLABbandpassButton.Value = true;
      app.CustomgaussianButton = uiradiobutton(app.filtTypeButtonGroup);
      app.CustomgaussianButton.Text = 'Custom gaussian';
      app.spkTab = uitab(app.TabGroup);
      app.spkTab.Title = 'Spike';
      app.fileNumSpkButtonGroup = uibuttongroup(app.spkTab);
      app.fileNumSpkButtonGroup.Title = 'Number of Recordings';
      app.SingleSpkButton = uiradiobutton(app.fileNumSpkButtonGroup);
      app.SingleSpkButton.Text = 'Single Recording';
      app.MultipleSpkButton = uiradiobutton(app.fileNumSpkButtonGroup);
      app.MultipleSpkButton.Text = 'Multiple (batch process)';
      app.MultipleSpkButton.Value = true;
      app.analyzeSpkButton = uibutton(app.spkTab, 'push');
      app.analyzeSpkButton.ButtonPushedFcn = createCallbackFcn(app, @analyzeSpkButtonPushed, true);
      app.analyzeSpkButton.Text = 'Analyze Spikes';
      app.plotSpkButton = uibutton(app.spkTab, 'push');
      app.plotSpkButton.ButtonPushedFcn = createCallbackFcn(app, @plotSpkButtonPushed, true);
      app.plotSpkButton.Enable = 'off';
      app.plotSpkButton.Text = 'Plot Spike Analysis';
      app.importSpkCheckBox = uicheckbox(app.spkTab);
      app.importSpkCheckBox.Text = 'Import spike events exported from pClamp';
      app.importSpkCheckBox.Value = true;
      app.spkSWRButtonGroup = uibuttongroup(app.spkTab);
      app.spkSWRButtonGroup.Title = 'Spike-SWR Options';
      app.useSWRDurationSpkButton = uiradiobutton(app.spkSWRButtonGroup);
      app.useSWRDurationSpkButton.Enable = 'off';
      app.useSWRDurationSpkButton.Text = 'Use detected SWR duration';
      app.useSWRDurationSpkButton.Value = true;
      app.useSWRWindowSpkButton = uiradiobutton(app.spkSWRButtonGroup);
      app.useSWRWindowSpkButton.Enable = 'off';
      app.useSWRWindowSpkButton.Text = 'Use standard swrWindow';
      app.useSWRDurationSpkLamp = uilamp(app.spkSWRButtonGroup);
      app.useSWRWindowSpkLamp = uilamp(app.spkSWRButtonGroup);
      app.expSWRSpkCheckbox = uicheckbox(app.spkSWRButtonGroup);
      app.expSWRSpkCheckbox.Enable = 'off';
      app.expSWRSpkCheckbox.Text = 'Export updated SWR Events';
      app.spkPhaseSWRCheckbox = uicheckbox(app.spkSWRButtonGroup);
      app.spkPhaseSWRCheckbox.ValueChangedFcn = createCallbackFcn(app, @spkPhaseSWRCheckboxValueChanged, true);
      app.spkPhaseSWRCheckbox.Enable = 'off';
      app.spkPhaseSWRCheckbox.Text = 'Spike-Phase Coupling';
      app.spkPhaseSWRLamp = uilamp(app.spkSWRButtonGroup);
      app.importSpkLamp = uilamp(app.spkTab);
      app.expSpkEvCheckBox = uicheckbox(app.spkTab);
      app.expSpkEvCheckBox.Text = 'Export Spike Events';
      app.expSpkEvCheckBox.Value = true;
      app.expBstEvCheckBox = uicheckbox(app.spkTab);
      app.expBstEvCheckBox.Enable = 'off';
      app.expBstEvCheckBox.Text = 'Export Burst Events';
      app.reanalyzeSpkButton = uibutton(app.spkTab, 'push');
      app.reanalyzeSpkButton.ButtonPushedFcn = createCallbackFcn(app, @reanalyzeSpkButtonPushed, true);
      app.reanalyzeSpkButton.Text = 'Re-Analyze Spikes';
      app.spkAnalysisTypeButtonGroup = uibuttongroup(app.spkTab);
      app.spkAnalysisTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @spkAnalysisTypeButtonGroupSelectionChanged, true);
      app.spkAnalysisTypeButtonGroup.Title = 'Type of Spike-LFP Analysis';
      app.swrSpkButton = uiradiobutton(app.spkAnalysisTypeButtonGroup);
      app.swrSpkButton.Text = 'Spike-SWR (detected events)';
      app.lfpSpkButton = uiradiobutton(app.spkAnalysisTypeButtonGroup);
      app.lfpSpkButton.Text = 'Spike-LFP (entire duration of file)';
      app.lfpSpkButton.Value = true;
      app.swrBstCheckBox = uicheckbox(app.spkAnalysisTypeButtonGroup);
      app.swrBstCheckBox.ValueChangedFcn = createCallbackFcn(app, @swrBstCheckBoxValueChanged, true);
      app.swrBstCheckBox.Enable = 'off';
      app.swrBstCheckBox.Text = 'Burst - SWR Analysis';
      app.swrBstLamp = uilamp(app.spkAnalysisTypeButtonGroup);
      app.swrSpkLamp = uilamp(app.spkAnalysisTypeButtonGroup);
      app.lfpSpkLamp = uilamp(app.spkAnalysisTypeButtonGroup);
      app.MinMaxOscillationSDThresholdforphaseLabel = uilabel(app.spkTab);
      app.MinMaxOscillationSDThresholdforphaseLabel.Text = 'Peak-Trough SD Multiple Threshold for Spike-Phase Calculation (0 = no min): ';
      app.sdMultPhaseField = uieditfield(app.spkTab, 'numeric');
      app.sdMultPhaseField.Value = 2;
      app.spkLFPButtonGroup = uibuttongroup(app.spkTab);
      app.spkLFPButtonGroup.Title = 'Spike-LFP Options';
      app.spkAmpLFPCheckbox = uicheckbox(app.spkLFPButtonGroup);
      app.spkAmpLFPCheckbox.Text = 'Spike-Amplitude Coupling';
      app.spkPhaseLFPCheckbox = uicheckbox(app.spkLFPButtonGroup);
      app.spkPhaseLFPCheckbox.ValueChangedFcn = createCallbackFcn(app, @spkPhaseLFPCheckboxValueChanged, true);
      app.spkPhaseLFPCheckbox.Text = 'Spike-Phase Coupling';
      app.spkPhaseLFPCheckbox.Value = true;
      app.spkPhaseLFPLamp = uilamp(app.spkLFPButtonGroup);
      app.spkAmpLFPLamp = uilamp(app.spkLFPButtonGroup);
      app.calcPhaseStatsCheckbox = uicheckbox(app.spkTab);
      app.calcPhaseStatsCheckbox.Text = 'Calculate Phase Statistics (requires Circular Statistics Toolbox) ';
      app.calcPhaseStatsCheckbox.Value = true;
      app.DownsamplePlotLabel_2 = uilabel(app.spkTab);
      app.DownsamplePlotLabel_2.Text = 'Downsample Plot:';
      app.dsPlotSpkField = uieditfield(app.spkTab, 'numeric');
      app.dsPlotSpkField.Value = 4;
      app.PSCTab = uitab(app.TabGroup);
      app.PSCTab.Title = 'PSC';
      app.fileNumPSCButtonGroup = uibuttongroup(app.PSCTab);
      app.fileNumPSCButtonGroup.Title = 'Number of Recordings';
      app.SinglePSCButton = uiradiobutton(app.fileNumPSCButtonGroup);
      app.SinglePSCButton.Text = 'Single Recording';
      app.MultiplePSCButton = uiradiobutton(app.fileNumPSCButtonGroup);
      app.MultiplePSCButton.Text = 'Multiple (batch process)';
      app.MultiplePSCButton.Value = true;
      app.analyzePSCButton = uibutton(app.PSCTab, 'push');
      app.analyzePSCButton.ButtonPushedFcn = createCallbackFcn(app, @analyzePSCButtonPushed, true);
      app.analyzePSCButton.Text = 'Analyze PSCs';
      app.plotPSCButton = uibutton(app.PSCTab, 'push');
      app.plotPSCButton.ButtonPushedFcn = createCallbackFcn(app, @plotPSCButtonPushed, true);
      app.plotPSCButton.Enable = 'off';
      app.plotPSCButton.Text = 'Plot PSC Analysis';
      app.importPSCCheckBox = uicheckbox(app.PSCTab);
      app.importPSCCheckBox.Text = 'Import PSC events exported from pClamp';
      app.importPSCCheckBox.Value = true;
      app.swrPSCCheckBox = uicheckbox(app.PSCTab);
      app.swrPSCCheckBox.ValueChangedFcn = createCallbackFcn(app, @swrPSCCheckBoxValueChanged, true);
      app.swrPSCCheckBox.Text = 'Calculate overlap of SWR and PSC events';
      app.swrPSCCheckBox.Value = true;
      app.swrPSCButtonGroup = uibuttongroup(app.PSCTab);
      app.useSWRDurationPSCButton = uiradiobutton(app.swrPSCButtonGroup);
      app.useSWRDurationPSCButton.Text = 'Use detected SWR duration';
      app.useSWRWindowPSCButton = uiradiobutton(app.swrPSCButtonGroup);
      app.useSWRWindowPSCButton.Text = 'Use standard swrWindow';
      app.useSWRWindowPSCButton.Value = true;
      app.calcEvMatrixPSCCheckBox = uicheckbox(app.swrPSCButtonGroup);
      app.calcEvMatrixPSCCheckBox.Enable = 'off';
      app.calcEvMatrixPSCCheckBox.Text = 'Calculate Event Matrix';
      app.calcEvMatrixPSCCheckBox.Value = true;
      app.parsePSCCheckBox = uicheckbox(app.swrPSCButtonGroup);
      app.parsePSCCheckBox.Enable = 'off';
      app.parsePSCCheckBox.Text = 'Parse PSCs into SWR event-locked files';
      app.parsePSCCheckBox.Value = true;
      app.useSWRDurationPSCLamp = uilamp(app.swrPSCButtonGroup);
      app.useSWRWindowPSCLamp = uilamp(app.swrPSCButtonGroup);
      app.parsePSCLamp = uilamp(app.swrPSCButtonGroup);
      app.calcEvMatrixPSCLamp = uilamp(app.swrPSCButtonGroup);
      app.expSWRPSCCheckbox = uicheckbox(app.swrPSCButtonGroup);
      app.expSWRPSCCheckbox.Text = 'Export updated SWR Events';
      app.expSWRPSCCheckbox.Value = true;
      app.importPSCLamp = uilamp(app.PSCTab);
      app.swrPSCLamp = uilamp(app.PSCTab);
      app.expPSCEvCheckBox = uicheckbox(app.PSCTab);
      app.expPSCEvCheckBox.Text = 'Export PSC Events';
      app.expPSCEvCheckBox.Value = true;
      app.reanalyzePSCButton = uibutton(app.PSCTab, 'push');
      app.reanalyzePSCButton.ButtonPushedFcn = createCallbackFcn(app, @reanalyzePSCButtonPushed, true);
      app.reanalyzePSCButton.Text = 'Re-Analyze PSCs';
      app.FiltersPSCPanel = uipanel(app.PSCTab);
      app.FiltersPSCPanel.Title = 'Cell Channel Filters';
      app.gammaFiltPSCCheckBox = uicheckbox(app.FiltersPSCPanel);
      app.gammaFiltPSCCheckBox.ValueChangedFcn = createCallbackFcn(app, @gammaFiltPSCCheckBoxValueChanged, true);
      app.gammaFiltPSCCheckBox.Text = 'Gamma Filter';
      app.gammaFiltPSCCheckBox.Value = true;
      app.rFiltPSCCheckBox = uicheckbox(app.FiltersPSCPanel);
      app.rFiltPSCCheckBox.ValueChangedFcn = createCallbackFcn(app, @rFiltPSCCheckBoxValueChanged, true);
      app.rFiltPSCCheckBox.Text = 'Ripple Filter';
      app.rFiltPSCCheckBox.Value = true;
      app.gammaFiltPSCLamp = uilamp(app.FiltersPSCPanel);
      app.rFiltPSCLamp = uilamp(app.FiltersPSCPanel);
      app.thetaLim1Label_3 = uilabel(app.FiltersPSCPanel);
      app.thetaLim1Label_3.Text = 'Lower:';
      app.gammaLim1PSCField = uieditfield(app.FiltersPSCPanel, 'numeric');
      app.gammaLim1PSCField.Value = 20;
      app.thetaLim2Label_3 = uilabel(app.FiltersPSCPanel);
      app.thetaLim2Label_3.Text = 'Upper:';
      app.gammaLim2PSCField = uieditfield(app.FiltersPSCPanel, 'numeric');
      app.gammaLim2PSCField.Value = 50;
      app.gammaLim1Label_4 = uilabel(app.FiltersPSCPanel);
      app.gammaLim1Label_4.Text = 'Lower:';
      app.rLim1PSCField = uieditfield(app.FiltersPSCPanel, 'numeric');
      app.rLim1PSCField.Value = 120;
      app.gammaLim2Label_4 = uilabel(app.FiltersPSCPanel);
      app.gammaLim2Label_4.Text = 'Upper:';
      app.rLim2PSCField = uieditfield(app.FiltersPSCPanel, 'numeric');
      app.rLim2PSCField.Value = 220;
      app.HzLabel_10 = uilabel(app.FiltersPSCPanel);
      app.HzLabel_10.Text = 'Hz';
      app.HzLabel_12 = uilabel(app.FiltersPSCPanel);
      app.HzLabel_12.Text = 'Hz';
      app.spectPSCCheckBox = uicheckbox(app.PSCTab);
      app.spectPSCCheckBox.ValueChangedFcn = createCallbackFcn(app, @spectPSCCheckBoxValueChanged, true);
      app.spectPSCCheckBox.Text = 'Spectral Analysis';
      app.spectPSCCheckBox.Value = true;
      app.spectPSCLamp = uilamp(app.PSCTab);
      app.HzLabel_15 = uilabel(app.PSCTab);
      app.HzLabel_15.Text = 'Hz';
      app.gammaLim1Label_7 = uilabel(app.PSCTab);
      app.gammaLim1Label_7.Text = 'Lower:';
      app.spectLim1PSCField = uieditfield(app.PSCTab, 'numeric');
      app.spectLim1PSCField.Value = 1;
      app.gammaLim2Label_7 = uilabel(app.PSCTab);
      app.gammaLim2Label_7.Text = 'Upper:';
      app.spectLim2PSCField = uieditfield(app.PSCTab, 'numeric');
      app.spectLim2PSCField.Value = 500;
      app.pscEventPolarityButtonGroup = uibuttongroup(app.PSCTab);
      app.pscPosPolarityButton = uiradiobutton(app.pscEventPolarityButtonGroup);
      app.pscPosPolarityButton.Text = '+';
      app.pscNegPolarityButton = uiradiobutton(app.pscEventPolarityButtonGroup);
      app.pscNegPolarityButton.Text = '-';
      app.pscNegPolarityButton.Value = true;
      app.PSCPolarityLabel = uilabel(app.PSCTab);
      app.PSCPolarityLabel.Text = 'PSC Polarity:';
      app.swrPSQCheckBox = uicheckbox(app.PSCTab);
      app.swrPSQCheckBox.Text = 'Calculate peri-SWR Charge';
      app.swrPSQCheckBox.Value = true;
      app.DownsamplePlotLabel_3 = uilabel(app.PSCTab);
      app.DownsamplePlotLabel_3.Text = 'Downsample Plot:';
      app.dsPlotPSCField = uieditfield(app.PSCTab, 'numeric');
      app.dsPlotPSCField.Value = 4;
      app.CaTab = uitab(app.TabGroup);
      app.CaTab.Title = 'Calcium';
      app.fileNumCaButtonGroup = uibuttongroup(app.CaTab);
      app.fileNumCaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @fileNumCaButtonGroupSelectionChanged, true);
      app.fileNumCaButtonGroup.Title = 'Number of Recordings';
      app.SingleButtonCa = uiradiobutton(app.fileNumCaButtonGroup);
      app.SingleButtonCa.Text = 'Single Recording';
      app.MultipleButtonCa = uiradiobutton(app.fileNumCaButtonGroup);
      app.MultipleButtonCa.Text = 'Multiple (batch process)';
      app.MultipleButtonCa.Value = true;
      app.SDMultThreshLabel_3 = uilabel(app.CaTab);
      app.SDMultThreshLabel_3.Text = 'SD Mult. Thresh:';
      app.sdMultCaField = uieditfield(app.CaTab, 'numeric');
      app.sdMultCaField.Value = 4;
      app.SamplingIntervalLabel = uilabel(app.CaTab);
      app.SamplingIntervalLabel.Text = 'Sampling Interval:';
      app.samplingIntCaField = uieditfield(app.CaTab, 'numeric');
      app.samplingIntCaField.Value = 1;
      app.msLabel = uilabel(app.CaTab);
      app.msLabel.Text = 'ms';
      app.analyzeCaButton = uibutton(app.CaTab, 'push');
      app.analyzeCaButton.ButtonPushedFcn = createCallbackFcn(app, @analyzeCaButtonPushed, true);
      app.analyzeCaButton.Text = 'Analyze Calcium';
      app.reanalyzeCaButton = uibutton(app.CaTab, 'push');
      app.reanalyzeCaButton.ButtonPushedFcn = createCallbackFcn(app, @reanalyzeCaButtonPushed, true);
      app.reanalyzeCaButton.Text = 'Re-Analyze Calcium';
      app.SDLabel_2 = uilabel(app.CaTab);
      app.SDLabel_2.Text = 'SD';
      app.expCaCheckbox = uicheckbox(app.CaTab);
      app.expCaCheckbox.Text = 'Export Ca Events';
      app.expCaCheckbox.Value = true;
      app.interpCheckbox = uicheckbox(app.CaTab);
      app.interpCheckbox.ValueChangedFcn = createCallbackFcn(app, @interpCheckboxValueChanged, true);
      app.interpCheckbox.Text = 'Interpolate Data';
      app.interpCheckbox.Value = true;
      app.peakDetectCaCheckbox = uicheckbox(app.CaTab);
      app.peakDetectCaCheckbox.ValueChangedFcn = createCallbackFcn(app, @peakDetectCaCheckboxValueChanged, true);
      app.peakDetectCaCheckbox.Text = 'Spontaneous Peak Detection';
      app.peakDetectCaCheckbox.Value = true;
      app.interpCaLamp = uilamp(app.CaTab);
      app.peakDetectCaLamp = uilamp(app.CaTab);
      app.plotCaButton = uibutton(app.CaTab, 'push');
      app.plotCaButton.ButtonPushedFcn = createCallbackFcn(app, @plotCaButtonPushed, true);
      app.plotCaButton.Enable = 'off';
      app.plotCaButton.Text = 'Plot Ca Analysis';
      app.swrCaButtonGroup = uibuttongroup(app.CaTab);
      app.swrCaButtonGroup.Title = ' ';
      app.useSWRDurationCaButton = uiradiobutton(app.swrCaButtonGroup);
      app.useSWRDurationCaButton.Enable = 'off';
      app.useSWRDurationCaButton.Text = 'Use detected SWR duration';
      app.useSWRDurationCaButton.Value = true;
      app.useSWRWindowCaButton = uiradiobutton(app.swrCaButtonGroup);
      app.useSWRWindowCaButton.Enable = 'off';
      app.useSWRWindowCaButton.Text = 'Use standard swrWindow';
      app.expSWRCaCheckbox = uicheckbox(app.swrCaButtonGroup);
      app.expSWRCaCheckbox.Enable = 'off';
      app.expSWRCaCheckbox.Text = 'Export updated SWR events';
      app.consistentThreshCheckBox = uicheckbox(app.CaTab);
      app.consistentThreshCheckBox.Text = 'Consistent Threshold for Batch';
      app.baseDetectCaButtonGroup = uibuttongroup(app.CaTab);
      app.baseDetectCaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @baseDetectCaButtonGroupSelectionChanged, true);
      app.baseDetectCaButtonGroup.Title = ' ';
      app.baseDetectCaButton1 = uiradiobutton(app.baseDetectCaButtonGroup);
      app.baseDetectCaButton1.Text = 'Lower Quantile';
      app.baseDetectCaButton2 = uiradiobutton(app.baseDetectCaButtonGroup);
      app.baseDetectCaButton2.Text = 'Gaussian Fit';
      app.baseDetectCaButton2.Value = true;
      app.baseQuantCaField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
      app.baseQuantCaField.ValueDisplayFormat = '%.2f';
      app.baseQuantCaField.Value = 0.8;
      app.FracDiffbetweenPeaksLabel_2 = uilabel(app.baseDetectCaButtonGroup);
      app.FracDiffbetweenPeaksLabel_2.Text = 'pkDiffMin:  ';
      app.pkDiffMinCaField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
      app.pkDiffMinCaField.Tooltip = {'Minimum fractional distance between double gaussian peaks to consider them equivalent = abs(b1-b2)/range, special logic for when double gaussians are on top of eachother, usually when baseline noise >> signal (default = 0.02)'};
      app.pkDiffMinCaField.Value = 0.02;
      app.PeakSimilarityLimitLabel = uilabel(app.baseDetectCaButtonGroup);
      app.PeakSimilarityLimitLabel.Text = 'pkSimLim:  ';
      app.pkSimLimCaField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
      app.pkSimLimCaField.Tooltip = {'Peak amplitude similarity metric = (a1^2 + a2^2)/(a1*a2), special logic needed for active signals where signal is comparable to baseline (default = 2)'};
      app.pkSimLimCaField.Value = 2;
      app.KurtosisMinLabel = uilabel(app.baseDetectCaButtonGroup);
      app.KurtosisMinLabel.Text = 'Kurtosis Range:';
      app.kurtosisMinCaField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
      app.kurtosisMinCaField.Tooltip = {'Min kurtosis limit to fit with 2 gaussians (otherwise skip 1st fit) (default = 0)'};
      app.kurtosisMinCaField.Value = 1.5;
      app.KurtosisMaxLabel = uilabel(app.baseDetectCaButtonGroup);
      app.KurtosisMaxLabel.Text = 'to';
      app.kurtosisMaxCaField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
      app.kurtosisMaxCaField.Tooltip = {'Max kurtosis limit, above which points will be excluded (otherwise fit can fail) (default = 5)'};
      app.kurtosisMaxCaField.Value = 5;
      app.ExclusionQuantileLabel = uilabel(app.baseDetectCaButtonGroup);
      app.ExclusionQuantileLabel.Text = 'Exclusion Quantile:';
      app.excludeQuantCaField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
      app.excludeQuantCaField.Tooltip = {'Quantile above which to exclude if max kurtosis limit reached (default = 0.95)'};
      app.excludeQuantCaField.Value = 0.95;
      app.plotFitHistoCaCheckBox = uicheckbox(app.baseDetectCaButtonGroup);
      app.plotFitHistoCaCheckBox.Tooltip = {'Option to plot histograms and fits for the signal (warning, will result in large number of figures, not recommended for batch)'};
      app.plotFitHistoCaCheckBox.Text = 'plot Fit Histograms';
      app.skewedBLCaCheckBox = uicheckbox(app.baseDetectCaButtonGroup);
      app.skewedBLCaCheckBox.Tooltip = {'Option to indicate skewed BL distribution, and use both gaussians just for BL (sometimes helpful for LFP RMS signals, less so for calcium dFoF)'};
      app.skewedBLCaCheckBox.Text = 'Skewed BL';
      app.KurtosisMinLabel_3 = uilabel(app.baseDetectCaButtonGroup);
      app.KurtosisMinLabel_3.Text = 'weightFactor:';
      app.weightFactorCaField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
      app.weightFactorCaField.Tooltip = {'Factor for the exponential weighting function that prioritizes fit of lower data points, higher value has stronger weighting. Higher values may work well better for high frame rate data (default = 5)'};
      app.weightFactorCaField.Value = 8;
      app.KurtosisMinLabel_4 = uilabel(app.baseDetectCaButtonGroup);
      app.KurtosisMinLabel_4.Text = 'minBins:';
      app.minBinsBLCaField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
      app.minBinsBLCaField.Tooltip = {'Minimum # of bins for histograms. Ensure sufficiently large for good fits, but may need to reduce for high interpolated sampling intervals (default = 500)'};
      app.minBinsBLCaField.Value = 500;
      app.KurtosisMinLabel_5 = uilabel(app.baseDetectCaButtonGroup);
      app.KurtosisMinLabel_5.Text = 'nSDLim:';
      app.nSDLimCaField = uieditfield(app.baseDetectCaButtonGroup, 'numeric');
      app.nSDLimCaField.Tooltip = {'Multiple of SD of gaussian fits used to estimate peak widths and constrain subsequent iterations (default = 2)'};
      app.nSDLimCaField.Value = 3;
      app.baseCorrectCaButtonGroup = uibuttongroup(app.CaTab);
      app.baseCorrectCaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @baseCorrectCaButtonGroupSelectionChanged, true);
      app.baseCorrectCaButtonGroup.Title = ' ';
      app.baseCorrectCaButton1 = uiradiobutton(app.baseCorrectCaButtonGroup);
      app.baseCorrectCaButton1.Text = 'Gaussian Filter';
      app.baseCorrectCaButton2 = uiradiobutton(app.baseCorrectCaButtonGroup);
      app.baseCorrectCaButton2.Text = 'Smoothed Average';
      app.baseCorrectCaButton2.Value = true;
      app.CaLim1Field = uieditfield(app.baseCorrectCaButtonGroup, 'numeric');
      app.CaLim1Field.Value = 0.03;
      app.CaLim2Field = uieditfield(app.baseCorrectCaButtonGroup, 'numeric');
      app.CaLim2Field.Value = 4;
      app.HzLabel_9 = uilabel(app.baseCorrectCaButtonGroup);
      app.HzLabel_9.Text = 'Hz';
      app.toLabel = uilabel(app.baseCorrectCaButtonGroup);
      app.toLabel.Text = 'to';
      app.FilterOrderLabel = uilabel(app.baseCorrectCaButtonGroup);
      app.FilterOrderLabel.Text = 'Filter Order:';
      app.CaFiltOrderField = uieditfield(app.baseCorrectCaButtonGroup, 'numeric');
      app.CaFiltOrderField.ValueDisplayFormat = '%.0f';
      app.CaFiltOrderField.Value = 80;
      app.FilterAlphaLabel = uilabel(app.baseCorrectCaButtonGroup);
      app.FilterAlphaLabel.Text = 'Filter Alpha:';
      app.CaFiltAlphaField = uieditfield(app.baseCorrectCaButtonGroup, 'numeric');
      app.CaFiltAlphaField.Value = 2.5;
      app.SmoothFactorLabel = uilabel(app.baseCorrectCaButtonGroup);
      app.SmoothFactorLabel.Text = 'Smooth Factor:';
      app.smoothFactorField = uieditfield(app.baseCorrectCaButtonGroup, 'numeric');
      app.smoothFactorField.Value = 0.25;
      app.SkipdetectionforfirstLabel = uilabel(app.CaTab);
      app.SkipdetectionforfirstLabel.Text = 'Skip detection for first:';
      app.skipDetectLimField = uieditfield(app.CaTab, 'numeric');
      app.skipDetectLimField.Value = 2;
      app.sLabel = uilabel(app.CaTab);
      app.sLabel.Text = 's';
      app.baseDetectCaCheckbox = uicheckbox(app.CaTab);
      app.baseDetectCaCheckbox.ValueChangedFcn = createCallbackFcn(app, @baseDetectCaCheckboxValueChanged, true);
      app.baseDetectCaCheckbox.Text = 'Baseline Detection';
      app.baseDetectCaCheckbox.Value = true;
      app.baseCorrectCaLamp = uilamp(app.CaTab);
      app.baseDetectCaLamp = uilamp(app.CaTab);
      app.stimCaPanel = uipanel(app.CaTab);
      app.stimCaPanel.Title = 'SWR Detection';
      app.msafterstimulationpeakLabel = uilabel(app.stimCaPanel);
      app.msafterstimulationpeakLabel.Text = 'ms after stimulation peak';
      app.fromLabel = uilabel(app.stimCaPanel);
      app.fromLabel.Text = 'from';
      app.stimCaLim1Field = uieditfield(app.stimCaPanel, 'numeric');
      app.stimCaLim1Field.Enable = 'off';
      app.toLabel_2 = uilabel(app.stimCaPanel);
      app.toLabel_2.Text = 'to';
      app.stimCaLim2Field = uieditfield(app.stimCaPanel, 'numeric');
      app.stimCaLim2Field.Enable = 'off';
      app.stimCaLim2Field.Value = 1000;
      app.expStimCaCheckbox = uicheckbox(app.stimCaPanel);
      app.expStimCaCheckbox.Enable = 'off';
      app.expStimCaCheckbox.Text = 'Export stimulation events';
      app.swrCaCheckbox = uicheckbox(app.CaTab);
      app.swrCaCheckbox.ValueChangedFcn = createCallbackFcn(app, @swrCaCheckboxValueChanged, true);
      app.swrCaCheckbox.Text = 'Calculate overlap of SWR and calcium events';
      app.swrCaLamp = uilamp(app.CaTab);
      app.stimCaCheckBox = uicheckbox(app.CaTab);
      app.stimCaCheckBox.ValueChangedFcn = createCallbackFcn(app, @stimCaCheckBoxValueChanged, true);
      app.stimCaCheckBox.Text = 'Calculate ΔF/F in response to stimulation';
      app.BaseFactorLabel_3 = uilabel(app.CaTab);
      app.BaseFactorLabel_3.Text = 'Base Factor:';
      app.sdBaseFactorCaField = uieditfield(app.CaTab, 'numeric');
      app.sdBaseFactorCaField.ValueDisplayFormat = '%11.3g';
      app.sdBaseFactorCaField.Value = 0.5;
      app.alignOptionGroup = uibuttongroup(app.CaTab);
      app.alignOptionGroup.Title = 'Align pClamp/dFoF files from:';
      app.alignOptionStartButton = uiradiobutton(app.alignOptionGroup);
      app.alignOptionStartButton.Enable = 'off';
      app.alignOptionStartButton.Text = 'Start';
      app.alignOptionStartButton.Value = true;
      app.alignOptionEndButton = uiradiobutton(app.alignOptionGroup);
      app.alignOptionEndButton.Enable = 'off';
      app.alignOptionEndButton.Text = 'End';
      app.baseCorrectCaCheckbox = uicheckbox(app.CaTab);
      app.baseCorrectCaCheckbox.ValueChangedFcn = createCallbackFcn(app, @baseCorrectCaCheckboxValueChanged, true);
      app.baseCorrectCaCheckbox.Text = 'Baseline Correction';
      app.baseCorrectCaCheckbox.Value = true;
      app.cellTypePanel = uipanel(app.CaTab);
      app.cellTypePanel.Title = ' ';
      app.Type1EditFieldLabel = uilabel(app.cellTypePanel);
      app.Type1EditFieldLabel.Text = 'Type 1:';
      app.cellType1EditField = uieditfield(app.cellTypePanel, 'text');
      app.cellType1EditField.Enable = 'off';
      app.cellType1EditField.Value = 'Back';
      app.Type3Label = uilabel(app.cellTypePanel);
      app.Type3Label.Text = 'Type 3:';
      app.cellType3EditField = uieditfield(app.cellTypePanel, 'text');
      app.cellType3EditField.Enable = 'off';
      app.cellType3EditField.Value = 'LateralFront';
      app.Type4Label = uilabel(app.cellTypePanel);
      app.Type4Label.Text = 'Type 4:';
      app.cellType4EditField = uieditfield(app.cellTypePanel, 'text');
      app.cellType4EditField.Enable = 'off';
      app.cellType4EditField.Value = 'MidFront';
      app.Type5Label = uilabel(app.cellTypePanel);
      app.Type5Label.Text = 'Type 5:';
      app.cellType5EditField = uieditfield(app.cellTypePanel, 'text');
      app.cellType5EditField.Enable = 'off';
      app.cellType5EditField.Value = 'Middle';
      app.Type2Label = uilabel(app.cellTypePanel);
      app.Type2Label.Text = 'Type 2:';
      app.cellType2EditField = uieditfield(app.cellTypePanel, 'text');
      app.cellType2EditField.Enable = 'off';
      app.cellType2EditField.Value = 'Midline';
      app.NumberofGroupsLabel = uilabel(app.cellTypePanel);
      app.NumberofGroupsLabel.Text = 'Number of Groups:';
      app.nCellTypesField = uieditfield(app.cellTypePanel, 'numeric');
      app.nCellTypesField.ValueChangedFcn = createCallbackFcn(app, @nCellTypesFieldValueChanged, true);
      app.nCellTypesField.Enable = 'off';
      app.nCellTypesField.Value = 5;
      app.Type6Label = uilabel(app.cellTypePanel);
      app.Type6Label.Text = 'Type 6:';
      app.cellType6EditField = uieditfield(app.cellTypePanel, 'text');
      app.cellType6EditField.Enable = 'off';
      app.cellType6EditField.Value = 'Other';
      app.DownsamplePlotLabel_4 = uilabel(app.CaTab);
      app.DownsamplePlotLabel_4.Text = 'Downsample Plot:';
      app.dsPlotCaField = uieditfield(app.CaTab, 'numeric');
      app.dsPlotCaField.Value = 4;
      app.cellTypeOptionCheckbox = uicheckbox(app.CaTab);
      app.cellTypeOptionCheckbox.ValueChangedFcn = createCallbackFcn(app, @cellTypeOptionCheckboxValueChanged, true);
      app.cellTypeOptionCheckbox.Text = 'Cell Type Option';
      app.plotCaCorrButton = uibutton(app.CaTab, 'push');
      app.plotCaCorrButton.ButtonPushedFcn = createCallbackFcn(app, @plotCaCorrButtonPushed, true);
      app.plotCaCorrButton.Enable = 'off';
      app.plotCaCorrButton.Text = 'Plot Correlations';
      app.CaFreqOptionCheckbox = uicheckbox(app.CaTab);
      app.CaFreqOptionCheckbox.Text = 'Set frequency of non-active cells = 0 (otherwise blank)';
      app.CaFreqOptionCheckbox.Value = true;
      app.CaFrameRateCheckbox = uicheckbox(app.CaTab);
      app.CaFrameRateCheckbox.ValueChangedFcn = createCallbackFcn(app, @CaFrameRateCheckboxValueChanged, true);
      app.CaFrameRateCheckbox.Text = 'Constant Frame Rate:';
      app.CaFrameRateCheckbox.Value = true;
      app.CaFrameRateField = uieditfield(app.CaTab, 'numeric');
      app.CaFrameRateField.Value = 9;
      app.HzLabel_19 = uilabel(app.CaTab);
      app.HzLabel_19.Text = 'Hz';
      app.ResultsTab = uitab(app.TabGroup);
      app.ResultsTab.Title = 'Results';
      app.resultTree = uitree(app.ResultsTab);
      app.resultTree.SelectionChangedFcn = createCallbackFcn(app, @resultTreeSelectionChanged, true);
      app.TypeLabel = uilabel(app.ResultsTab);
      app.TypeLabel.Text = 'Type:';
      app.varTypeTextArea = uitextarea(app.ResultsTab);
      app.varTypeTextArea.Editable = 'off';
      app.varValueTable = uitable(app.ResultsTab);
      app.varValueTable.ColumnName = '';
      app.varValueTable.RowName = {};
      app.exportEventsButton = uibutton(app.ResultsTab, 'push');
      app.exportEventsButton.ButtonPushedFcn = createCallbackFcn(app, @exportEventsButtonPushed, true);
      app.exportEventsButton.Enable = 'off';
      app.exportEventsButton.Text = 'Export Events';
      app.dataFile1Loaded = uilabel(app.swrAnalysisUIFigure);
      app.dataFile1Loaded.Text = '';
      app.dataFile2Loaded = uilabel(app.swrAnalysisUIFigure);
      app.dataFile2Loaded.Text = '';
      app.openFile2Button = uibutton(app.swrAnalysisUIFigure, 'push');
      app.openFile2Button.ButtonPushedFcn = createCallbackFcn(app, @openFile2ButtonPushed, true);
      app.openFile2Button.Enable = 'off';
      app.openFile2Button.Text = 'Open File 2';
      app.closeFileButton = uibutton(app.swrAnalysisUIFigure, 'push');
      app.closeFileButton.ButtonPushedFcn = createCallbackFcn(app, @closeFileButtonPushed, true);
      app.closeFileButton.Enable = 'off';
      app.closeFileButton.Text = 'Close File';
      app.openFileButton = uibutton(app.swrAnalysisUIFigure, 'push');
      app.openFileButton.ButtonPushedFcn = createCallbackFcn(app, @openFileButtonPushed, true);
      app.openFileButton.Text = 'Open Analyzed File';
      app.openPrevFileButton = uibutton(app.swrAnalysisUIFigure, 'push');
      app.openPrevFileButton.ButtonPushedFcn = createCallbackFcn(app, @openPrevFileButtonPushed, true);
      app.openPrevFileButton.Enable = 'off';
      app.openPrevFileButton.Text = '<';
      app.openNextFileButton = uibutton(app.swrAnalysisUIFigure, 'push');
      app.openNextFileButton.ButtonPushedFcn = createCallbackFcn(app, @openNextFileButtonPushed, true);
      app.openNextFileButton.Enable = 'off';
      app.openNextFileButton.Text = '>';
      app.saveFileButton = uibutton(app.swrAnalysisUIFigure, 'push');
      app.saveFileButton.ButtonPushedFcn = createCallbackFcn(app, @saveFileButtonPushed, true);
      app.saveFileButton.Enable = 'off';
      app.saveFileButton.Text = 'Save File';
      app.openFile3Button = uibutton(app.swrAnalysisUIFigure, 'push');
      app.openFile3Button.ButtonPushedFcn = createCallbackFcn(app, @openFile3ButtonPushed, true);
      app.openFile3Button.Enable = 'off';
      app.openFile3Button.Text = 'Open File 3';
      app.dataFile3Loaded = uilabel(app.swrAnalysisUIFigure);
      app.dataFile3Loaded.Text = '';
      app.expAveCheckbox = uicheckbox(app.swrAnalysisUIFigure);
      app.expAveCheckbox.ValueChangedFcn = createCallbackFcn(app, @expAveCheckboxValueChanged, true);
      app.expAveCheckbox.Text = 'Export average files';
      app.expAveCheckbox.Value = true;
      app.transposeCheckbox = uicheckbox(app.swrAnalysisUIFigure);
      app.transposeCheckbox.Text = 'Transpose export';
      % Show the figure after all components are created
      app.swrAnalysisUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = swrAnalysis

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.swrAnalysisUIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.swrAnalysisUIFigure)
    end
  end
end